classdef GAGE_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        NormalModeTab                   matlab.ui.container.Tab
        RecordPanel                     matlab.ui.container.Panel
        NumSegments                     matlab.ui.control.NumericEditField
        SegmentsLabel                   matlab.ui.control.Label
        TriggerTimeoutEditField         matlab.ui.control.NumericEditField
        TriggerTimeoutEditField_3Label  matlab.ui.control.Label
        TriggerdelayEditField           matlab.ui.control.NumericEditField
        TriggerdelayEditField_3Label    matlab.ui.control.Label
        PreTriggerEditField             matlab.ui.control.NumericEditField
        PreTriggerEditFieldLabel        matlab.ui.control.Label
        TriggerEditField                matlab.ui.control.NumericEditField
        TriggerEditField_3Label         matlab.ui.control.Label
        SegmentSizeRecord               matlab.ui.control.NumericEditField
        SegmentSizeEditField_2Label_5   matlab.ui.control.Label
        FsDropDown_2                    matlab.ui.control.DropDown
        FsDropDown_5Label               matlab.ui.control.Label
        searchButton                    matlab.ui.control.Button
        RecordButton                    matlab.ui.control.Button
        DirectoryEditField              matlab.ui.control.EditField
        DirectoryEditField_4Label       matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        NameEditField_3Label            matlab.ui.control.Label
        SegmentSize                     matlab.ui.control.NumericEditField
        SegmentSizeEditField_2Label     matlab.ui.control.Label
        StartButton                     matlab.ui.control.StateButton
        ImpedanceButtonGroup            matlab.ui.container.ButtonGroup
        MOhm1Button                     matlab.ui.control.RadioButton
        Ohm50Button                     matlab.ui.control.RadioButton
        InputRangeButtonGroup           matlab.ui.container.ButtonGroup
        V10Button                       matlab.ui.control.RadioButton
        V5Button                        matlab.ui.control.RadioButton
        V1Button                        matlab.ui.control.RadioButton
        FsDropDown                      matlab.ui.control.DropDown
        FsDropDownLabel                 matlab.ui.control.Label
        ModeDropDown                    matlab.ui.control.DropDown
        ModeDropDown_2Label             matlab.ui.control.Label
        IQModeTab                       matlab.ui.container.Tab
        RecordPanel_2                   matlab.ui.container.Panel
        SegmentSizeRecord_5             matlab.ui.control.NumericEditField
        SegmentsLabel_2                 matlab.ui.control.Label
        TriggerTimeoutEditField_2       matlab.ui.control.NumericEditField
        TriggerTimeoutEditField_2Label  matlab.ui.control.Label
        TriggerdelayEditField_2         matlab.ui.control.NumericEditField
        TriggerdelayEditField_2Label    matlab.ui.control.Label
        PreTriggerEditField_2           matlab.ui.control.NumericEditField
        PreTriggerEditField_2Label      matlab.ui.control.Label
        TriggerEditField_2              matlab.ui.control.NumericEditField
        TriggerEditField_2Label         matlab.ui.control.Label
        SegmentSizeRecord_4             matlab.ui.control.NumericEditField
        SegmentSizeEditField_2Label_6   matlab.ui.control.Label
        FsDropDown_4                    matlab.ui.control.DropDown
        FsDropDown_4Label               matlab.ui.control.Label
        searchButton_2                  matlab.ui.control.Button
        RecordButton_2                  matlab.ui.control.Button
        DirectoryEditField_2            matlab.ui.control.EditField
        DirectoryEditField_2Label       matlab.ui.control.Label
        NameEditField_2                 matlab.ui.control.EditField
        NameEditField_2Label            matlab.ui.control.Label
        IQCirclesPanel                  matlab.ui.container.Panel
        IQ4Label                        matlab.ui.control.Label
        IQ3Label                        matlab.ui.control.Label
        IQ2Label                        matlab.ui.control.Label
        IQ1Label                        matlab.ui.control.Label
        ChLabel_2                       matlab.ui.control.Label
        ChLabel                         matlab.ui.control.Label
        I4                              matlab.ui.control.NumericEditField
        Q4                              matlab.ui.control.NumericEditField
        Q3                              matlab.ui.control.NumericEditField
        I3                              matlab.ui.control.NumericEditField
        I2                              matlab.ui.control.NumericEditField
        Q2                              matlab.ui.control.NumericEditField
        I1                              matlab.ui.control.NumericEditField
        Q1                              matlab.ui.control.NumericEditField
        StartButton_IQ                  matlab.ui.control.StateButton
        ImpedanceButtonGroup_2          matlab.ui.container.ButtonGroup
        MOhm1Button_2                   matlab.ui.control.RadioButton
        Ohm50Button_2                   matlab.ui.control.RadioButton
        InputRangeButtonGroup_2         matlab.ui.container.ButtonGroup
        V10Button_2                     matlab.ui.control.RadioButton
        V5Button_2                      matlab.ui.control.RadioButton
        V1Button_2                      matlab.ui.control.RadioButton
        FsDropDown_3                    matlab.ui.control.DropDown
        FsDropDown_3Label               matlab.ui.control.Label
        SegmentSize_2                   matlab.ui.control.NumericEditField
        SegmentSizeEditField_2Label_3   matlab.ui.control.Label
        ModeDropDown_3                  matlab.ui.control.DropDown
        ModeDropDown_3Label             matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        TextArea                        matlab.ui.control.TextArea
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        mode % Description
        samplingrate
        samplingrate_record
        inputrange
        impedance
        segsize
        segsize_record
        name
        directory
        Data_Matrix
        handle
    end
    
    methods (Access = private)
        
        function SetupDAQ(app, handle,Mode, Fs,SegmentSize, pretrig, Impd, InpRange, segments, ExtRange,trigsource,trigdelay,trigtimeout)
                % Set the acquisition, channel and trigger parameters for the system and
                % calls ConfigureAcquisition, ConfigureChannel and ConfigureTrigger.           
            [ret, sysinfo] = CsMl_GetSystemInfo(handle);
            CsMl_ErrorHandler(ret, 1, handle);
            
            acqInfo.SampleRate = Fs;
            acqInfo.ExtClock = 0;
            acqInfo.Mode = CsMl_Translate(Mode, 'Mode');
            acqInfo.SegmentCount = segments; %number of records or segments to acquire
            acqInfo.Depth = SegmentSize-pretrig;  %post-trigger depth
            acqInfo.SegmentSize = SegmentSize; %size of memory allocated for the segment pre+post trig
            acqInfo.TriggerTimeout = trigtimeout; %in us
            acqInfo.TriggerHoldoff = 0;%SegmentSize-dpoints;
            acqInfo.TriggerDelay = trigdelay ; %  9216 for 65 MS number of samples that the CompuScope hardware
            % will wait after the trigger event occurs before beginning the count-down of the depth counters
            acqInfo.TimeStampConfig = 0;
            
            [ret] = CsMl_ConfigureAcquisition(handle, acqInfo);
            CsMl_ErrorHandler(ret, 1, handle);
            
            % Set up all the channels even though
            % they might not all be used. For example
            % in a 2 board master / slave system, in single channel
            % mode only channels 1 and 3 are used.
            for i = 1:sysinfo.ChannelCount
                chan(i).Channel = i;
                chan(i).Coupling = CsMl_Translate('DC', 'Coupling');
                chan(i).DiffInput = 0;
                chan(i).InputRange = InpRange; %2000; %mV
                chan(i).Impedance = Impd; %Ohm
                chan(i).DcOffset = 0; %channel input DC offset in millivolts
                chan(i).DirectAdc = 0;
                chan(i).Filter = 0; 
            end   
            
            [ret] = CsMl_ConfigureChannel(handle, chan);
            CsMl_ErrorHandler(ret, 1, handle);
            
            trig.Trigger = 1;
            trig.Slope = CsMl_Translate('Positive', 'Slope');
            trig.Level = 20;  % 30% of -10 +10V input range = 3V
            trig.Source = trigsource; %-1 is external trigger
            trig.ExtCoupling = CsMl_Translate('DC', 'ExtCoupling');
            trig.ExtRange = ExtRange;  %external trigger full scale input range in millivolts.
            
            [ret] = CsMl_ConfigureTrigger(handle, trig);
            CsMl_ErrorHandler(ret, 1, handle);
            
            ret = 1;

        end
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.mode=app.ModeDropDown.Value;
            app.samplingrate=str2num(app.FsDropDown.Value);
            app.samplingrate_record=str2num(app.FsDropDown_2.Value);
            app.segsize=app.SegmentSize.Value;
            app.inputrange=20000;
            app.impedance=1e6;
            app.segsize_record=app.SegmentSizeRecord.Value;
            app.DirectoryEditField.Value=string(pwd);
            app.DirectoryEditField_2.Value=string(pwd);
            app.directory=string(pwd);
           
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            app.mode = app.ModeDropDown.Value;
        end

        % Value changed function: FsDropDown, FsDropDown_3
        function FsDropDownValueChanged(app, event)
            app.samplingrate = str2num(app.FsDropDown.Value);
        end

        % Value changed function: FsDropDown_2, FsDropDown_4
        function FsDropDown_2ValueChanged(app, event)
            app.samplingrate_record= str2num(app.FsDropDown_2.Value);
        end

        % Selection changed function: InputRangeButtonGroup, 
        % ...and 1 other component
        function InputRangeButtonGroupSelectionChanged(app, event)
            selectedButton = app.InputRangeButtonGroup.SelectedObject;
            if selectedButton==app.V1Button
                app.inputrange=2000;
            end
            if selectedButton==app.V5Button
                app.inputrange=10000;
            end
            if selectedButton==app.V10Button
                app.inputrange=20000;
            end
        end

        % Selection changed function: ImpedanceButtonGroup, 
        % ...and 1 other component
        function ImpedanceButtonGroupSelectionChanged(app, event)
            selectedButton = app.ImpedanceButtonGroup.SelectedObject;
            if selectedButton==app.Ohm50Button
                app.impedance=50;
                app.TextArea.Value=[app.TextArea.Value;'impedance set to 50 Ohm';app.TextArea.Value];
            end
            if selectedButton==app.MOhm1Button
                app.impedance=1000000;
                app.TextArea.Value=['impedance set to 1 MOhm';app.TextArea.Value];
            end
        end

        % Callback function
        function SegmentSizeRecordValueChanged(app, event)
            app.segsize_record = app.SegmentSizeRecord.Value;
            
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            app.name = app.NameEditField.Value;  
        end

        % Value changed function: DirectoryEditField_2
        function DirectoryEditFieldValueChanged(app, event)
            app.directory = app.DirectoryEditField.Value;
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            if value==true
                %initialize drivers
                systems = CsMl_Initialize;
                CsMl_ErrorHandler(systems);

                [ret, handle] = CsMl_GetSystem;
                app.handle=handle
                CsMl_ErrorHandler(ret);
                
                [ret, sysinfo] = CsMl_GetSystemInfo(app.handle);
                CsMl_ErrorHandler(ret);
                
                s = sprintf('-----Board name: %s\n', sysinfo.BoardName);
                app.TextArea.Value=[app.TextArea.Value;s];
                
                % Setup
                SetupDAQ(app,app.handle,app.mode,app.samplingrate, app.segsize, 0, app.impedance, app.inputrange, 1, 10000,-1,0,0);
                cla(app.UIAxes); 
                cla(app.UIAxes2); 
                ax=app.UIAxes;
                ax2=app.UIAxes2;
                xtime=[1:app.segsize].*1/app.samplingrate;
                
                h=animatedline(ax,'MaximumNumPoints',app.segsize);
                h2=animatedline(ax2,'MaximumNumPoints',app.segsize);
                clearpoints(h);
                clearpoints(h2);
            
                app.TextArea.Value=['continous mode activated';app.TextArea.Value];
                while app.StartButton.Value==true
                    [ret] = CsMl_Commit(app.handle);
                    if value~=true
                        break
                    end
                    CsMl_ErrorHandler(0,ret);
                    [ret, acqInfo] = CsMl_QueryAcquisition(app.handle);
                    %Acquire pre-trigger data and await trigger
                    ret = CsMl_Capture(app.handle);
                    CsMl_ErrorHandler(ret, 0, app.handle);
                        
                    status = CsMl_QueryStatus(app.handle);
                    while status ~= 0
                          status = CsMl_QueryStatus(app.handle);
                    end
                    transfer.Channel = 1;
                    transfer.Mode = CsMl_Translate('TimeStamp', 'TxMode');
                    transfer.Length = acqInfo.SegmentCount;
                     transfer.Segment = 1;
                    [ret, tsdata, tickfr] = CsMl_Transfer(handle, transfer);
                    transfer.Mode = CsMl_Translate('Default', 'TxMode');
                    transfer.Start = -acqInfo.TriggerHoldoff;
                    transfer.Length = acqInfo.SegmentSize;
                    MaskedMode = bitand(acqInfo.Mode, 15);
                    ChannelsPerBoard = sysinfo.ChannelCount / sysinfo.BoardCount;
                    ChannelSkip = ChannelsPerBoard / MaskedMode;
                    
                    % Format a string with the number of segments and channels so all filenames
                    % have the same number of characters.
        
                    Data=zeros(sysinfo.ChannelCount,app.segsize,acqInfo.SegmentCount); %array for zeros for each channel
                    
                    for channel = 1:ChannelSkip:sysinfo.ChannelCount
                        transfer.Channel = channel;
                        for i = 1:acqInfo.SegmentCount
                            transfer.Segment = i;
                            [ret, data, actual] = CsMl_Transfer(app.handle, transfer);
                            Data(channel,:,i)= data;
                        end
                        
                    addpoints(h,xtime,Data(1,:,1));
                    drawnow limitrate
                    Y=fft(Data(1,:,1));
                    P2 = abs(Y/app.segsize);
                    P1 = P2(1:app.segsize/2+1);
                    P1(2:end-1) = 2*P1(2:end-1);
                    f = app.samplingrate*(0:(app.segsize/2))/app.segsize;
                    addpoints(h2,f,P1);
                    drawnow limitrate
                    
                    
                    end
                end
                app.TextArea.Value=['continous mode ended';app.TextArea.Value];
                ret = CsMl_FreeSystem(app.handle);
                g=sprintf('systemrelease, value: %d', value);
                app.TextArea.Value=[g;app.TextArea.Value];
            end
            
        end

        % Value changed function: SegmentSize, SegmentSizeRecord_4, 
        % ...and 1 other component
        function SegmentSizeValueChanged(app, event)
            app.segsize= app.SegmentSize.Value;  
        end

        % Button pushed function: searchButton, searchButton_2
        function searchButtonPushed(app, event)
            app.directory = uigetdir;
            app.DirectoryEditField.Value=app.directory;
            app.DirectoryEditField_2.Value=app.directory;
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            %initialize drivers
            app.TextArea.Value=['initializing system...';app.TextArea.Value];
            systems = CsMl_Initialize;
            CsMl_ErrorHandler(systems);

            [ret, handle] = CsMl_GetSystem;
            app.handle=handle
            CsMl_ErrorHandler(ret);
                
            [ret, sysinfo] = CsMl_GetSystemInfo(app.handle);
            CsMl_ErrorHandler(ret);
                
            s = sprintf('-----Board name: %s\n', sysinfo.BoardName);
            app.TextArea.Value=[s;app.TextArea.Value];    
            % Setup
            SetupDAQ(app,app.handle,app.mode,app.samplingrate_record,app.segsize_record,app.PreTriggerEditField.Value, app.impedance, app.inputrange, app.NumSegments.Value, 10000, app.TriggerEditField.Value,app.TriggerdelayEditField,app.TriggerTimeoutEditField.Value)
            [ret] = CsMl_Commit(app.handle);
            CsMl_ErrorHandler(ret, 1, app.handle);
            [ret, acqInfo] = CsMl_QueryAcquisition(app.handle);
            %Acquire pre-trigger data and await trigger
            app.TextArea.Value=['Capturing...';app.TextArea.Value];
            ret = CsMl_Capture(app.handle);
            CsMl_ErrorHandler(ret, 1, app.handle);
                
            status = CsMl_QueryStatus(app.handle);
            while status ~= 0
                  status = CsMl_QueryStatus(app.handle);
            end
            transfer.Channel = 1;
            transfer.Mode = CsMl_Translate('TimeStamp', 'TxMode');
            transfer.Length = acqInfo.SegmentCount;
             transfer.Segment = 1;
            [ret, tsdata, tickfr] = CsMl_Transfer(handle, transfer);
            transfer.Mode = CsMl_Translate('Default', 'TxMode');
            transfer.Start = -acqInfo.TriggerHoldoff;
            transfer.Length = acqInfo.SegmentSize;
            MaskedMode = bitand(acqInfo.Mode, 15);
            ChannelsPerBoard = sysinfo.ChannelCount / sysinfo.BoardCount;
            ChannelSkip = ChannelsPerBoard / MaskedMode;
            
            % Format a string with the number of segments and channels so all filenames
            % have the same number of characters.
            app.TextArea.Value=['Transferring started...';app.TextArea.Value];

            Data=zeros(sysinfo.ChannelCount,app.segsize_record,acqInfo.SegmentCount); %array for zeros for each channel
            
            for channel = 1:ChannelSkip:sysinfo.ChannelCount
                transfer.Channel = channel;
                for i = 1:acqInfo.SegmentCount
                    transfer.Segment = i;
                    fprintf('Channel: %d\n',channel);
                    [ret, data, actual] = CsMl_Transfer(app.handle, transfer);
                    Data(channel,:,i)= data;
                end
            end
            ret = CsMl_FreeSystem(app.handle);
            app.TextArea.Value=['iSystem released succesfully';app.TextArea.Value];
            app.TextArea.Value=['transfer finished... writing to mat file started';app.TextArea.Value];
            save(strcat(app.directory,'\',app.name,'.mat'),'Data');
            app.TextArea.Value=['writing finished... start diplaying';app.TextArea.Value];
            app.Data_Matrix=reshape(Data,size(Data,1),[]);
            f = app.samplingrate_record*(0:(app.segsize_record/2))/app.segsize_record;
            cla(app.UIAxes); 
            cla(app.UIAxes2); 
            for channel = 1:ChannelSkip:sysinfo.ChannelCount 
                hold(app.UIAxes,"on");
                hold(app.UIAxes2,"on");
                plot(app.UIAxes,app.Data_Matrix(channel,:));
                Y=fft(Data(channel,:,1));
                P2 = abs(Y/app.segsize_record);
                P1 = P2(1:app.segsize_record/2+1);
                P1(2:end-1) = 2*P1(2:end-1);
                plot(app.UIAxes2,f,P1);
            end
            hold(app.UIAxes,"off");
            hold(app.UIAxes2,"off");

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {498, 498};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {403, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            ret = CsMl_FreeAllSystems;
        end

        % Value changed function: TriggerEditField
        function TriggerEditFieldValueChanged(app, event)
            value = app.TriggerEditField.Value;
            
        end

        % Value changed function: StartButton_IQ
        function StartButton_IQValueChanged(app, event)
            value = app.StartButton_IQ.Value;
            app.mode = app.ModeDropDown_3.Value;
            if value==true
                %initialize drivers
                systems = CsMl_Initialize;
                CsMl_ErrorHandler(systems);

                [ret, handle] = CsMl_GetSystem;
                app.handle=handle
                CsMl_ErrorHandler(ret);
                
                [ret, sysinfo] = CsMl_GetSystemInfo(app.handle);
                CsMl_ErrorHandler(ret);
                
                s = sprintf('-----Board name: %s\n', sysinfo.BoardName);
                app.TextArea.Value=[app.TextArea.Value;s];
                
                % Setup
                SetupDAQ(app,app.handle,app.mode,app.samplingrate,app.segsize, 0, app.impedance, app.inputrange, 1, 10000,-1,0,0);
                cla(app.UIAxes); 
                cla(app.UIAxes2); 
                ax=app.UIAxes;
                xlim(ax, [-app.inputrange/2/1000,app.inputrange/2/1000])
                ylim(ax, [-app.inputrange/2/1000,app.inputrange/2/1000])
                ax2=app.UIAxes2;
                xtime=[1:app.segsize].*1/app.samplingrate;
                
                if strcmp(app.mode,'Dual')
                    h_iq1=animatedline(ax,'MaximumNumPoints',app.segsize);
                    h2_i1=animatedline(ax2,'MaximumNumPoints',app.segsize);
                    clearpoints( h_iq1);
                    clearpoints( h2_i1);
                end
                
                if strcmp(app.mode,'Octal')
                    h_iq1=animatedline(ax,'MaximumNumPoints',app.segsize,'Color','r');
                    h_iq2=animatedline(ax,'MaximumNumPoints',app.segsize,'Color','b');
                    h_iq3=animatedline(ax,'MaximumNumPoints',app.segsize,'Color','g');
                    h_iq4=animatedline(ax,'MaximumNumPoints',app.segsize);
                    h2_i1=animatedline(ax2,'MaximumNumPoints',app.segsize);
                    clearpoints( h_iq1);
                    clearpoints( h_iq2);
                    clearpoints( h_iq3);
                    clearpoints( h_iq4);
                    clearpoints( h2_i1);

                end
                
            
                app.TextArea.Value=['continous mode activated';app.TextArea.Value];
                
                while app.StartButton_IQ.Value==true
                    [ret] = CsMl_Commit(app.handle);
                    if value~=true
                        break
                    end
                    CsMl_ErrorHandler(0,ret);
                    [ret, acqInfo] = CsMl_QueryAcquisition(app.handle);
                    %Acquire pre-trigger data and await trigger
                    ret = CsMl_Capture(app.handle);
                    CsMl_ErrorHandler(ret, 0, app.handle);
                        
                    status = CsMl_QueryStatus(app.handle);
                    while status ~= 0
                          status = CsMl_QueryStatus(app.handle);
                    end
                    transfer.Channel = 1;
                    transfer.Mode = CsMl_Translate('TimeStamp', 'TxMode');
                    transfer.Length = acqInfo.SegmentCount;
                     transfer.Segment = 1;
                    [ret, tsdata, tickfr] = CsMl_Transfer(handle, transfer);
                    transfer.Mode = CsMl_Translate('Default', 'TxMode');
                    transfer.Start = -acqInfo.TriggerHoldoff;
                    transfer.Length = acqInfo.SegmentSize;
                    MaskedMode = bitand(acqInfo.Mode, 15);
                    ChannelsPerBoard = sysinfo.ChannelCount / sysinfo.BoardCount;
                    ChannelSkip = ChannelsPerBoard / MaskedMode;
                    
                    % Format a string with the number of segments and channels so all filenames
                    % have the same number of characters.
        
                    Data=zeros(sysinfo.ChannelCount,app.segsize,acqInfo.SegmentCount); %array for zeros for each channel
                    
                    for channel = 1:ChannelSkip:sysinfo.ChannelCount
                        transfer.Channel = channel;
                        for i = 1:acqInfo.SegmentCount
                            transfer.Segment = i;
                            [ret, data, actual] = CsMl_Transfer(app.handle, transfer);
                            Data(channel,:,i)= data;
                        end
                        
                    Y=fft(Data(app.I1.Value,:,i));
                    P2 = abs(Y/app.segsize);
                    P1 = P2(1:app.segsize/2+1);
                    P1(2:end-1) = 2*P1(2:end-1);
                    f = app.samplingrate*(0:(app.segsize/2))/app.segsize;
                    addpoints(h2_i1,f,P1);
                    drawnow limitrate
                    if strcmp(app.mode,'Dual')
                        addpoints(h_iq1,Data(app.I1.Value,:,i),Data(app.Q1.Value,:,i));
                        drawnow limitrate
                    end
                    if strcmp(app.mode,'Octal')
                        addpoints(h_iq1,Data(app.I1.Value,:,i),Data(app.Q1.Value,:,i));
                        addpoints(h_iq2,Data(app.I2.Value,:,i),Data(app.Q2.Value,:,i));
                        addpoints(h_iq3,Data(app.I3.Value,:,i),Data(app.Q3.Value,:,i));
                        addpoints(h_iq4,Data(app.I4.Value,:,i),Data(app.Q4.Value,:,i));
                        drawnow limitrate
                    end
                    
                    
                    end
                end
                app.TextArea.Value=['continous mode ended';app.TextArea.Value];
                ret = CsMl_FreeSystem(app.handle);
                g=sprintf('systemrelease, value: %d', value);
                app.TextArea.Value=[g;app.TextArea.Value];
            end
            
        end

        % Value changed function: ModeDropDown_3
        function ModeDropDown_3ValueChanged(app, event)
            app.mode = app.ModeDropDown_3.Value;
            app.TextArea.Value=[app.ModeDropDown_3.Value;app.TextArea.Value]; 
            if strcmp(app.mode,'Single')
                app.TextArea.Value=['Single Mode not possible for IQ mode';app.TextArea.Value];                
            end
            if strcmp(app.mode,'Dual')
                app.I1.Value=1;
                app.Q1.Value=5;
                app.I2.Value=0;
                app.Q2.Value=0;
                app.I3.Value=0;
                app.Q3.Value=0;
                app.I4.Value=0;
                app.Q4.Value=0;
            end
            if strcmp(app.mode,'Octal')
                app.I1.Value=1;
                app.Q1.Value=2;
                app.I2.Value=3;
                app.Q2.Value=4;
                app.I3.Value=5;
                app.Q3.Value=6;
                app.I4.Value=7;
                app.Q4.Value=8;
            end
            
        end

        % Button pushed function: RecordButton_2
        function RecordButton_2Pushed(app, event)
            %initialize drivers
            app.TextArea.Value=['initializing system...';app.TextArea.Value];
            systems = CsMl_Initialize;
            CsMl_ErrorHandler(systems);

            [ret, handle] = CsMl_GetSystem;
            app.handle=handle
            CsMl_ErrorHandler(ret);
                
            [ret, sysinfo] = CsMl_GetSystemInfo(app.handle);
            CsMl_ErrorHandler(ret);
                
            s = sprintf('-----Board name: %s\n', sysinfo.BoardName);
            app.TextArea.Value=[s;app.TextArea.Value];    
            % Setup
            SetupDAQ(app,app.handle,app.mode,app.samplingrate_record,app.segsize_record,app.PreTriggerEditField_2.Value, app.impedance, app.inputrange, app.NumSegments.Value, 10000, app.TriggerEditField_2.Value,app.TriggerdelayEditField_2,app.TriggerTimeoutEditField_2.Value)
            [ret] = CsMl_Commit(app.handle);
            CsMl_ErrorHandler(ret, 1, app.handle);
            [ret, acqInfo] = CsMl_QueryAcquisition(app.handle);
            %Acquire pre-trigger data and await trigger
            app.TextArea.Value=['Capturing...';app.TextArea.Value];
            ret = CsMl_Capture(app.handle);
            CsMl_ErrorHandler(ret, 1, app.handle);
                
            status = CsMl_QueryStatus(app.handle);
            while status ~= 0
                  status = CsMl_QueryStatus(app.handle);
            end
            transfer.Channel = 1;
            transfer.Mode = CsMl_Translate('TimeStamp', 'TxMode');
            transfer.Length = acqInfo.SegmentCount;
             transfer.Segment = 1;
            [ret, tsdata, tickfr] = CsMl_Transfer(handle, transfer);
            transfer.Mode = CsMl_Translate('Default', 'TxMode');
            transfer.Start = -acqInfo.TriggerHoldoff;
            transfer.Length = acqInfo.SegmentSize;
            MaskedMode = bitand(acqInfo.Mode, 15);
            ChannelsPerBoard = sysinfo.ChannelCount / sysinfo.BoardCount;
            ChannelSkip = ChannelsPerBoard / MaskedMode;
            
            % Format a string with the number of segments and channels so all filenames
            % have the same number of characters.
            app.TextArea.Value=['Transferring started...';app.TextArea.Value];

            Data=zeros(sysinfo.ChannelCount,app.segsize_record,acqInfo.SegmentCount); %array for zeros for each channel
            
            for channel = 1:ChannelSkip:sysinfo.ChannelCount
                transfer.Channel = channel;
                for i = 1:acqInfo.SegmentCount
                    transfer.Segment = i;
                    fprintf('Channel: %d\n',channel);
                    [ret, data, actual] = CsMl_Transfer(app.handle, transfer);
                    Data(channel,:,i)= data;
                end
            end
            ret = CsMl_FreeSystem(app.handle);
            app.TextArea.Value=['iSystem released succesfully';app.TextArea.Value];
            app.TextArea.Value=['transfer finished... writing to mat file started';app.TextArea.Value];
            save(strcat(app.directory,'\',app.NameEditField_2.Value,'.mat'),'Data');
            app.TextArea.Value=['writing finished... start diplaying';app.TextArea.Value];
            app.Data_Matrix=reshape(Data,size(Data,1),[]);
            f = app.samplingrate_record*(0:(app.segsize_record/2))/app.segsize_record;
            cla(app.UIAxes); 
            cla(app.UIAxes2); 
            for channel = 1:ChannelSkip:sysinfo.ChannelCount 
                hold(app.UIAxes,"on");
                hold(app.UIAxes2,"on");
                plot(app.UIAxes,app.Data_Matrix(app.I1.Value,:),app.Data_Matrix(app.Q1.Value,:));
                Y=fft(Data(app.Q1.Value,:,1));
                P2 = abs(Y/app.segsize_record);
                P1 = P2(1:app.segsize_record/2+1);
                P1(2:end-1) = 2*P1(2:end-1);
                plot(app.UIAxes2,f,P1);
            end
            hold(app.UIAxes,"off");
            hold(app.UIAxes2,"off");
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.NormalModeTab = uitab(app.TabGroup);
            app.NormalModeTab.Title = 'Normal Mode';
            app.ModeDropDown_2Label = uilabel(app.NormalModeTab);
            app.ModeDropDown_2Label.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.NormalModeTab);
            app.ModeDropDown.Items = {'Single', 'Dual', 'Octal'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'Single';
            app.FsDropDownLabel = uilabel(app.NormalModeTab);
            app.FsDropDownLabel.Text = 'Fs';
            app.FsDropDown = uidropdown(app.NormalModeTab);
            app.FsDropDown.Items = {'65e6', '5e6', '5e5'};
            app.FsDropDown.ValueChangedFcn = createCallbackFcn(app, @FsDropDownValueChanged, true);
            app.FsDropDown.Value = '5e5';
            app.InputRangeButtonGroup = uibuttongroup(app.NormalModeTab);
            app.InputRangeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InputRangeButtonGroupSelectionChanged, true);
            app.InputRangeButtonGroup.Title = 'Input Range';
            app.V1Button = uiradiobutton(app.InputRangeButtonGroup);
            app.V1Button.Text = '1V';
            app.V5Button = uiradiobutton(app.InputRangeButtonGroup);
            app.V5Button.Text = '5V';
            app.V10Button = uiradiobutton(app.InputRangeButtonGroup);
            app.V10Button.Text = '10V';
            app.V10Button.Value = true;
            app.ImpedanceButtonGroup = uibuttongroup(app.NormalModeTab);
            app.ImpedanceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImpedanceButtonGroupSelectionChanged, true);
            app.ImpedanceButtonGroup.Title = 'Impedance';
            app.Ohm50Button = uiradiobutton(app.ImpedanceButtonGroup);
            app.Ohm50Button.Text = '50 Ohm';
            app.MOhm1Button = uiradiobutton(app.ImpedanceButtonGroup);
            app.MOhm1Button.Text = '1 MOhm';
            app.MOhm1Button.Value = true;
            app.StartButton = uibutton(app.NormalModeTab, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.SegmentSizeEditField_2Label = uilabel(app.NormalModeTab);
            app.SegmentSizeEditField_2Label.Text = 'SegmentSize';
            app.SegmentSize = uieditfield(app.NormalModeTab, 'numeric');
            app.SegmentSize.ValueChangedFcn = createCallbackFcn(app, @SegmentSizeValueChanged, true);
            app.SegmentSize.Value = 50000;
            app.RecordPanel = uipanel(app.NormalModeTab);
            app.RecordPanel.Title = 'Record Panel';
            app.NameEditField_3Label = uilabel(app.RecordPanel);
            app.NameEditField_3Label.Text = 'Name';
            app.NameEditField = uieditfield(app.RecordPanel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.DirectoryEditField_4Label = uilabel(app.RecordPanel);
            app.DirectoryEditField_4Label.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.RecordPanel, 'text');
            app.RecordButton = uibutton(app.RecordPanel, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.searchButton = uibutton(app.RecordPanel, 'push');
            app.searchButton.ButtonPushedFcn = createCallbackFcn(app, @searchButtonPushed, true);
            app.searchButton.Text = 'search';
            app.FsDropDown_5Label = uilabel(app.RecordPanel);
            app.FsDropDown_5Label.Text = 'Fs';
            app.FsDropDown_2 = uidropdown(app.RecordPanel);
            app.FsDropDown_2.Items = {'65e6', '5e6', '5e5'};
            app.FsDropDown_2.ValueChangedFcn = createCallbackFcn(app, @FsDropDown_2ValueChanged, true);
            app.FsDropDown_2.Value = '5e5';
            app.SegmentSizeEditField_2Label_5 = uilabel(app.RecordPanel);
            app.SegmentSizeEditField_2Label_5.Text = 'SegmentSize';
            app.SegmentSizeRecord = uieditfield(app.RecordPanel, 'numeric');
            app.SegmentSizeRecord.RoundFractionalValues = 'on';
            app.SegmentSizeRecord.Value = 50000;
            app.TriggerEditField_3Label = uilabel(app.RecordPanel);
            app.TriggerEditField_3Label.Text = 'Trigger';
            app.TriggerEditField = uieditfield(app.RecordPanel, 'numeric');
            app.TriggerEditField.ValueChangedFcn = createCallbackFcn(app, @TriggerEditFieldValueChanged, true);
            app.PreTriggerEditFieldLabel = uilabel(app.RecordPanel);
            app.PreTriggerEditFieldLabel.Text = 'Pre-Trigger';
            app.PreTriggerEditField = uieditfield(app.RecordPanel, 'numeric');
            app.TriggerdelayEditField_3Label = uilabel(app.RecordPanel);
            app.TriggerdelayEditField_3Label.Text = 'Trigger delay';
            app.TriggerdelayEditField = uieditfield(app.RecordPanel, 'numeric');
            app.TriggerTimeoutEditField_3Label = uilabel(app.RecordPanel);
            app.TriggerTimeoutEditField_3Label.Text = 'Trigger Time-out';
            app.TriggerTimeoutEditField = uieditfield(app.RecordPanel, 'numeric');
            app.SegmentsLabel = uilabel(app.RecordPanel);
            app.SegmentsLabel.Text = '# Segments';
            app.NumSegments = uieditfield(app.RecordPanel, 'numeric');
            app.NumSegments.RoundFractionalValues = 'on';
            app.NumSegments.Value = 1;
            app.IQModeTab = uitab(app.TabGroup);
            app.IQModeTab.Title = 'IQ Mode';
            app.ModeDropDown_3Label = uilabel(app.IQModeTab);
            app.ModeDropDown_3Label.Text = 'Mode';
            app.ModeDropDown_3 = uidropdown(app.IQModeTab);
            app.ModeDropDown_3.Items = {'Dual', 'Octal'};
            app.ModeDropDown_3.ValueChangedFcn = createCallbackFcn(app, @ModeDropDown_3ValueChanged, true);
            app.ModeDropDown_3.Value = 'Dual';
            app.SegmentSizeEditField_2Label_3 = uilabel(app.IQModeTab);
            app.SegmentSizeEditField_2Label_3.Text = 'SegmentSize';
            app.SegmentSize_2 = uieditfield(app.IQModeTab, 'numeric');
            app.SegmentSize_2.ValueChangedFcn = createCallbackFcn(app, @SegmentSizeValueChanged, true);
            app.SegmentSize_2.Value = 50000;
            app.FsDropDown_3Label = uilabel(app.IQModeTab);
            app.FsDropDown_3Label.Text = 'Fs';
            app.FsDropDown_3 = uidropdown(app.IQModeTab);
            app.FsDropDown_3.Items = {'65e6', '5e6', '5e5', '5e4'};
            app.FsDropDown_3.ValueChangedFcn = createCallbackFcn(app, @FsDropDownValueChanged, true);
            app.FsDropDown_3.Value = '5e5';
            app.InputRangeButtonGroup_2 = uibuttongroup(app.IQModeTab);
            app.InputRangeButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @InputRangeButtonGroupSelectionChanged, true);
            app.InputRangeButtonGroup_2.Title = 'Input Range';
            app.V1Button_2 = uiradiobutton(app.InputRangeButtonGroup_2);
            app.V1Button_2.Text = '1V';
            app.V1Button_2.Value = true;
            app.V5Button_2 = uiradiobutton(app.InputRangeButtonGroup_2);
            app.V5Button_2.Text = '5V';
            app.V10Button_2 = uiradiobutton(app.InputRangeButtonGroup_2);
            app.V10Button_2.Text = '10V';
            app.ImpedanceButtonGroup_2 = uibuttongroup(app.IQModeTab);
            app.ImpedanceButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ImpedanceButtonGroupSelectionChanged, true);
            app.ImpedanceButtonGroup_2.Title = 'Impedance';
            app.Ohm50Button_2 = uiradiobutton(app.ImpedanceButtonGroup_2);
            app.Ohm50Button_2.Text = '50 Ohm';
            app.Ohm50Button_2.Value = true;
            app.MOhm1Button_2 = uiradiobutton(app.ImpedanceButtonGroup_2);
            app.MOhm1Button_2.Text = '1 MOhm';
            app.StartButton_IQ = uibutton(app.IQModeTab, 'state');
            app.StartButton_IQ.ValueChangedFcn = createCallbackFcn(app, @StartButton_IQValueChanged, true);
            app.StartButton_IQ.Text = 'Start';
            app.IQCirclesPanel = uipanel(app.IQModeTab);
            app.IQCirclesPanel.Title = 'IQ Circle(s)';
            app.Q1 = uieditfield(app.IQCirclesPanel, 'numeric');
            app.Q1.Value = 5;
            app.I1 = uieditfield(app.IQCirclesPanel, 'numeric');
            app.I1.Value = 1;
            app.Q2 = uieditfield(app.IQCirclesPanel, 'numeric');
            app.I2 = uieditfield(app.IQCirclesPanel, 'numeric');
            app.I3 = uieditfield(app.IQCirclesPanel, 'numeric');
            app.Q3 = uieditfield(app.IQCirclesPanel, 'numeric');
            app.Q4 = uieditfield(app.IQCirclesPanel, 'numeric');
            app.I4 = uieditfield(app.IQCirclesPanel, 'numeric');
            app.ChLabel = uilabel(app.IQCirclesPanel);
            app.ChLabel.Text = 'Ch';
            app.ChLabel_2 = uilabel(app.IQCirclesPanel);
            app.ChLabel_2.Text = 'Ch';
            app.IQ1Label = uilabel(app.IQCirclesPanel);
            app.IQ1Label.Text = 'IQ1';
            app.IQ2Label = uilabel(app.IQCirclesPanel);
            app.IQ2Label.Text = 'IQ2';
            app.IQ3Label = uilabel(app.IQCirclesPanel);
            app.IQ3Label.Text = 'IQ3';
            app.IQ4Label = uilabel(app.IQCirclesPanel);
            app.IQ4Label.Text = 'IQ4';
            app.RecordPanel_2 = uipanel(app.IQModeTab);
            app.RecordPanel_2.Title = 'Record Panel';
            app.NameEditField_2Label = uilabel(app.RecordPanel_2);
            app.NameEditField_2Label.Text = 'Name';
            app.NameEditField_2 = uieditfield(app.RecordPanel_2, 'text');
            app.DirectoryEditField_2Label = uilabel(app.RecordPanel_2);
            app.DirectoryEditField_2Label.Text = 'Directory';
            app.DirectoryEditField_2 = uieditfield(app.RecordPanel_2, 'text');
            app.DirectoryEditField_2.ValueChangedFcn = createCallbackFcn(app, @DirectoryEditFieldValueChanged, true);
            app.RecordButton_2 = uibutton(app.RecordPanel_2, 'push');
            app.RecordButton_2.ButtonPushedFcn = createCallbackFcn(app, @RecordButton_2Pushed, true);
            app.RecordButton_2.Text = 'Record';
            app.searchButton_2 = uibutton(app.RecordPanel_2, 'push');
            app.searchButton_2.ButtonPushedFcn = createCallbackFcn(app, @searchButtonPushed, true);
            app.searchButton_2.Text = 'search';
            app.FsDropDown_4Label = uilabel(app.RecordPanel_2);
            app.FsDropDown_4Label.Text = 'Fs';
            app.FsDropDown_4 = uidropdown(app.RecordPanel_2);
            app.FsDropDown_4.Items = {'65e6', '5e6', '5e5'};
            app.FsDropDown_4.ValueChangedFcn = createCallbackFcn(app, @FsDropDown_2ValueChanged, true);
            app.FsDropDown_4.Value = '5e5';
            app.SegmentSizeEditField_2Label_6 = uilabel(app.RecordPanel_2);
            app.SegmentSizeEditField_2Label_6.Text = 'SegmentSize';
            app.SegmentSizeRecord_4 = uieditfield(app.RecordPanel_2, 'numeric');
            app.SegmentSizeRecord_4.RoundFractionalValues = 'on';
            app.SegmentSizeRecord_4.ValueChangedFcn = createCallbackFcn(app, @SegmentSizeValueChanged, true);
            app.SegmentSizeRecord_4.Value = 50000;
            app.TriggerEditField_2Label = uilabel(app.RecordPanel_2);
            app.TriggerEditField_2Label.Text = 'Trigger';
            app.TriggerEditField_2 = uieditfield(app.RecordPanel_2, 'numeric');
            app.PreTriggerEditField_2Label = uilabel(app.RecordPanel_2);
            app.PreTriggerEditField_2Label.Text = 'Pre-Trigger';
            app.PreTriggerEditField_2 = uieditfield(app.RecordPanel_2, 'numeric');
            app.TriggerdelayEditField_2Label = uilabel(app.RecordPanel_2);
            app.TriggerdelayEditField_2Label.Text = 'Trigger delay';
            app.TriggerdelayEditField_2 = uieditfield(app.RecordPanel_2, 'numeric');
            app.TriggerTimeoutEditField_2Label = uilabel(app.RecordPanel_2);
            app.TriggerTimeoutEditField_2Label.Text = 'Trigger Time-out';
            app.TriggerTimeoutEditField_2 = uieditfield(app.RecordPanel_2, 'numeric');
            app.SegmentsLabel_2 = uilabel(app.RecordPanel_2);
            app.SegmentsLabel_2.Text = '# Segments';
            app.SegmentSizeRecord_5 = uieditfield(app.RecordPanel_2, 'numeric');
            app.SegmentSizeRecord_5.RoundFractionalValues = 'on';
            app.SegmentSizeRecord_5.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.RightPanel);
            app.UIAxes = uiaxes(app.Panel);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            app.UIAxes2 = uiaxes(app.Panel);
            xlabel(app.UIAxes2, 'Frequency [Hz]')
            ylabel(app.UIAxes2, 'Amplitude')
            app.TextArea = uitextarea(app.Panel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GAGE_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end