classdef LDV_PA_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        NormalModeTab                   matlab.ui.container.Tab
        RecordPanel                     matlab.ui.container.Panel
        NumSegments                     matlab.ui.control.NumericEditField
        SegmentsLabel                   matlab.ui.control.Label
        TriggerTimeoutEditField         matlab.ui.control.NumericEditField
        TriggerTimeoutEditField_3Label  matlab.ui.control.Label
        TriggerdelayEditField           matlab.ui.control.NumericEditField
        TriggerdelayEditField_3Label    matlab.ui.control.Label
        PreTriggerEditField             matlab.ui.control.NumericEditField
        PreTriggerEditFieldLabel        matlab.ui.control.Label
        TriggerEditField                matlab.ui.control.NumericEditField
        TriggerEditField_3Label         matlab.ui.control.Label
        SegmentSizeRecord               matlab.ui.control.NumericEditField
        SegmentSizeEditField_2Label_5   matlab.ui.control.Label
        FsDropDown_2                    matlab.ui.control.DropDown
        FsDropDown_5Label               matlab.ui.control.Label
        searchButton                    matlab.ui.control.Button
        RecordButton                    matlab.ui.control.Button
        DirectoryEditField              matlab.ui.control.EditField
        DirectoryEditField_4Label       matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        NameEditField_3Label            matlab.ui.control.Label
        SegmentSize                     matlab.ui.control.NumericEditField
        SegmentSizeEditField_2Label     matlab.ui.control.Label
        StartButton                     matlab.ui.control.StateButton
        ImpedanceButtonGroup            matlab.ui.container.ButtonGroup
        Ohm1MButton                     matlab.ui.control.RadioButton
        Ohm50Button                     matlab.ui.control.RadioButton
        InputRangeButtonGroup           matlab.ui.container.ButtonGroup
        V10Button                       matlab.ui.control.RadioButton
        V5Button                        matlab.ui.control.RadioButton
        V1Button                        matlab.ui.control.RadioButton
        FsDropDown                      matlab.ui.control.DropDown
        FsDropDownLabel                 matlab.ui.control.Label
        ModeDropDown                    matlab.ui.control.DropDown
        ModeDropDown_2Label             matlab.ui.control.Label
        IQModeTab                       matlab.ui.container.Tab
        namefitfileEditField            matlab.ui.control.EditField
        namefitfileEditFieldLabel       matlab.ui.control.Label
        FitEllipseButton                matlab.ui.control.Button
        RecordIQsingle                  matlab.ui.control.Button
        RecordinganddemodulatePanel     matlab.ui.container.Panel
        DemodulationaveragingSwitch     matlab.ui.control.Switch
        DemodulationaveragingSwitchLabel  matlab.ui.control.Label
        fittingfileEditField            matlab.ui.control.EditField
        fittingfileEditFieldLabel       matlab.ui.control.Label
        NumSegmentsRecord_IQdemo        matlab.ui.control.NumericEditField
        SegmentsLabel_2                 matlab.ui.control.Label
        TriggerTimeoutusEditField       matlab.ui.control.NumericEditField
        TriggerTimeoutusEditFieldLabel  matlab.ui.control.Label
        TriggerdelaysEditField          matlab.ui.control.NumericEditField
        TriggerdelaysEditFieldLabel     matlab.ui.control.Label
        PreTriggersEditField            matlab.ui.control.NumericEditField
        PreTriggersEditFieldLabel       matlab.ui.control.Label
        TriggerEditField_2              matlab.ui.control.NumericEditField
        TriggerEditField_2Label         matlab.ui.control.Label
        SegmentSizeRecord_4             matlab.ui.control.NumericEditField
        SegmentSizeEditField_2Label_6   matlab.ui.control.Label
        FsDropDown_4                    matlab.ui.control.DropDown
        FsDropDown_4Label               matlab.ui.control.Label
        searchButton_2                  matlab.ui.control.Button
        RecordDemodIQ                   matlab.ui.control.Button
        DirectoryEditField_2            matlab.ui.control.EditField
        DirectoryEditField_2Label       matlab.ui.control.Label
        NameEditField_2                 matlab.ui.control.EditField
        NameEditField_2Label            matlab.ui.control.Label
        IQCirclesPanel                  matlab.ui.container.Panel
        IQ4Label                        matlab.ui.control.Label
        IQ3Label                        matlab.ui.control.Label
        IQ2Label                        matlab.ui.control.Label
        IQ1Label                        matlab.ui.control.Label
        ChLabel_2                       matlab.ui.control.Label
        ChLabel                         matlab.ui.control.Label
        I4                              matlab.ui.control.NumericEditField
        Q4                              matlab.ui.control.NumericEditField
        Q3                              matlab.ui.control.NumericEditField
        I3                              matlab.ui.control.NumericEditField
        I2                              matlab.ui.control.NumericEditField
        Q2                              matlab.ui.control.NumericEditField
        I1                              matlab.ui.control.NumericEditField
        Q1                              matlab.ui.control.NumericEditField
        StartButton_IQ                  matlab.ui.control.StateButton
        ImpedanceButtonGroup_2          matlab.ui.container.ButtonGroup
        MOhm1Button_2                   matlab.ui.control.RadioButton
        Ohm50Button_2                   matlab.ui.control.RadioButton
        InputRangeButtonGroup_2         matlab.ui.container.ButtonGroup
        V10Button_2                     matlab.ui.control.RadioButton
        V5Button_2                      matlab.ui.control.RadioButton
        V1Button_2                      matlab.ui.control.RadioButton
        FsDropDown_3                    matlab.ui.control.DropDown
        FsDropDown_3Label               matlab.ui.control.Label
        SegmentSize_2                   matlab.ui.control.NumericEditField
        SegmentSizeEditField_2Label_3   matlab.ui.control.Label
        ModeDropDown_3                  matlab.ui.control.DropDown
        ModeDropDown_3Label             matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        TextArea                        matlab.ui.control.TextArea
        UIAxes2                         matlab.ui.control.UIAxes
        timeAxes                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        mode % Description
        samplingrate
        samplingrate_record
        inputrange
        impedance
        segsize
        segsize_record
        name
        directory
        Data_Matrix
        handle
    end
    
    methods (Access = private)
        
        function SetupDAQ(app, handle,Mode, Fs,SegmentSize, pretrig, Impd, InpRange, segments, ExtRange,trigsource,trigdelay,trigtimeout)
                % Set the acquisition, channel and trigger parameters for the system and
                % calls ConfigureAcquisition, ConfigureChannel and ConfigureTrigger.           
            [ret, sysinfo] = CsMl_GetSystemInfo(handle);
            CsMl_ErrorHandler(ret, 1, handle);
            
            acqInfo.SampleRate = Fs;
            acqInfo.ExtClock = 0;
            acqInfo.Mode = CsMl_Translate(Mode, 'Mode');
            acqInfo.SegmentCount = segments; %number of records or segments to acquire
            acqInfo.Depth = SegmentSize-pretrig;  %post-trigger depth
            acqInfo.SegmentSize = SegmentSize; %size of memory allocated for the segment pre+post trig
            acqInfo.TriggerTimeout = trigtimeout; %in us
            acqInfo.TriggerHoldoff = pretrig;
            acqInfo.TriggerDelay = trigdelay ; %  9216 for 65 MS number of samples that the CompuScope hardware
            % will wait after the trigger event occurs before beginning the count-down of the depth counters
            acqInfo.TimeStampConfig = 0;
            
            [ret] = CsMl_ConfigureAcquisition(handle, acqInfo);
            CsMl_ErrorHandler(ret, 1, handle);
            
            % Set up all the channels even though
            % they might not all be used. For example
            % in a 2 board master / slave system, in single channel
            % mode only channels 1 and 3 are used.
            for i = 1:sysinfo.ChannelCount
                chan(i).Channel = i;
                chan(i).Coupling = CsMl_Translate('DC', 'Coupling');
                chan(i).DiffInput = 0;
                chan(i).InputRange = InpRange; %2000; %mV
                chan(i).Impedance = Impd; %Ohm
                chan(i).DcOffset = 0; %channel input DC offset in millivolts
                chan(i).DirectAdc = 0;
                chan(i).Filter = 0; 
            end   
            
            [ret] = CsMl_ConfigureChannel(handle, chan);
            CsMl_ErrorHandler(ret, 1, handle);
            
            trig.Trigger = 1;
            trig.Slope = CsMl_Translate('Positive', 'Slope');
            trig.Level = 5;  % 30% of -10 +10V input range = 3V
            trig.Source = trigsource; %-1 is external trigger
            trig.ExtCoupling = CsMl_Translate('DC', 'ExtCoupling');
            trig.ExtRange = ExtRange;  %external trigger full scale input range in millivolts.
            
            [ret] = CsMl_ConfigureTrigger(handle, trig);
            CsMl_ErrorHandler(ret, 1, handle);
            
            ret = 1;

        end
       
        
        function ellipse_t = fit_ellipse(app,x ,y ,axis_handle)
        %
        % fit_ellipse - finds the best fit to an ellipse for the given set of points.
        %
        % Format:   ellipse_t = fit_ellipse( x,y,axis_handle )
        %
        % Input:    x,y         - a set of points in 2 column vectors. AT LEAST 5 points are needed !
        %           axis_handle - optional. a handle to an axis, at which the estimated ellipse 
        %                         will be drawn along with it's axes
        %
        % Output:   ellipse_t - structure that defines the best fit to an ellipse
        %                       a           - sub axis (radius) of the X axis of the non-tilt ellipse
        %                       b           - sub axis (radius) of the Y axis of the non-tilt ellipse
        %                       phi         - orientation in radians of the ellipse (tilt)
        %                       X0          - center at the X axis of the non-tilt ellipse
        %                       Y0          - center at the Y axis of the non-tilt ellipse
        %                       X0_in       - center at the X axis of the tilted ellipse
        %                       Y0_in       - center at the Y axis of the tilted ellipse
        %                       long_axis   - size of the long axis of the ellipse
        %                       short_axis  - size of the short axis of the ellipse
        %                       status      - status of detection of an ellipse
        %
        % Note:     if an ellipse was not detected (but a parabola or hyperbola), then
        %           an empty structure is returned
        % =====================================================================================
        %                  Ellipse Fit using Least Squares criterion
        % =====================================================================================
        % We will try to fit the best ellipse to the given measurements. the mathematical
        % representation of use will be the CONIC Equation of the Ellipse which is:
        % 
        %    Ellipse = a*x^2 + b*x*y + c*y^2 + d*x + e*y + f = 0
        %   
        % The fit-estimation method of use is the Least Squares method (without any weights)
        % The estimator is extracted from the following equations:
        %
        %    g(x,y;A) := a*x^2 + b*x*y + c*y^2 + d*x + e*y = f
        %
        %    where:
        %       A   - is the vector of parameters to be estimated (a,b,c,d,e)
        %       x,y - is a single measurement
        %
        % We will define the cost function to be:
        %
        %   Cost(A) := (g_c(x_c,y_c;A)-f_c)'*(g_c(x_c,y_c;A)-f_c)
        %            = (X*A+f_c)'*(X*A+f_c) 
        %            = A'*X'*X*A + 2*f_c'*X*A + N*f^2
        %
        %   where:
        %       g_c(x_c,y_c;A) - vech element of g_c() is g(x,y;A)
        %       X              - a matrix of the form: [x_c.^2, x_c.*y_c, y_c.^2, x_c, y_c ]
        %       f_c            - is actually defined as ones(length(f),1)*f
        %ctor function of ALL the measurements
        %                        ea
        % Derivation of the Cost function with respect to the vector of parameters "A" yields:
        %
        %   A'*X'*X = -f_c'*X = -f*ones(1,length(f_c))*X = -f*sum(X)
        %
        % Which yields the estimator:
        %
        %       ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        %       |  A_least_squares = -f*sum(X)/(X'*X) ->(normalize by -f) = sum(X)/(X'*X)  |
        %       ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        %
        % (We will normalize the variables by (-f) since "f" is unknown and can be accounted for later on)
        %  
        % NOW, all that is left to do is to extract the parameters from the Conic Equation.
        % We will deal the vector A into the variables: (A,B,C,D,E) and assume F = -1;
        %
        %    Recall the conic representation of an ellipse:
        % 
        %       A*x^2 + B*x*y + C*y^2 + D*x + E*y + F = 0
        % 
        % We will check if the ellipse has a tilt (=orientation). The orientation is present
        % if the coefficient of the term "x*y" is not zero. If so, we first need to remove the
        % tilt of the ellipse.
        %
        % If the parameter "B" is not equal to zero, then we have an orientation (tilt) to the ellipse.
        % we will remove the tilt of the ellipse so as to remain with a conic representation of an 
        % ellipse without a tilt, for which the math is more simple:
        %
        % Non tilt conic rep.:  A`*x^2 + C`*y^2 + D`*x + E`*y + F` = 0
        %
        % We will remove the orientation using the following substitution:
        %   
        %   Replace x with cx+sy and y with -sx+cy such that the conic representation is:
        %   
        %   A(cx+sy)^2 + B(cx+sy)(-sx+cy) + C(-sx+cy)^2 + D(cx+sy) + E(-sx+cy) + F = 0
        %
        %   where:      c = cos(phi)    ,   s = sin(phi)
        %
        %   and simplify...
        %
        %       x^2(A*c^2 - Bcs + Cs^2) + xy(2A*cs +(c^2-s^2)B -2Ccs) + ...
        %           y^2(As^2 + Bcs + Cc^2) + x(Dc-Es) + y(Ds+Ec) + F = 0
        %
        %   The orientation is easily found by the condition of (B_new=0) which results in:
        % 
        %   2A*cs +(c^2-s^2)B -2Ccs = 0  ==> phi = 1/2 * atan( b/(c-a) )
        %   
        %   Now the constants   c=cos(phi)  and  s=sin(phi)  can be found, and from them
        %   all the other constants A`,C`,D`,E` can be found.
        %
        %   A` = A*c^2 - B*c*s + C*s^2                  D` = D*c-E*s
        %   B` = 2*A*c*s +(c^2-s^2)*B -2*C*c*s = 0      E` = D*s+E*c 
        %   C` = A*s^2 + B*c*s + C*c^2
        %
        % Next, we want the representation of the non-tilted ellipse to be as:
        %
        %       Ellipse = ( (X-X0)/a )^2 + ( (Y-Y0)/b )^2 = 1
        %
        %       where:  (X0,Y0) is the center of the ellipse
        %               a,b     are the ellipse "radiuses" (or sub-axis)
        %
        % Using a square completion method we will define:
        %       
        %       F`` = -F` + (D`^2)/(4*A`) + (E`^2)/(4*C`)
        %
        %       Such that:    a`*(X-X0)^2 = A`(X^2 + X*D`/A` + (D`/(2*A`))^2 )
        %                     c`*(Y-Y0)^2 = C`(Y^2 + Y*E`/C` + (E`/(2*C`))^2 )
        %
        %       which yields the transformations:
        %       
        %           X0  =   -D`/(2*A`)
        %           Y0  =   -E`/(2*C`)
        %           a   =   sqrt( abs( F``/A` ) )
        %           b   =   sqrt( abs( F``/C` ) )
        %
        % And finally we can define the remaining parameters:
        %
        %   long_axis   = 2 * max( a,b )
        %   short_axis  = 2 * min( a,b )
        %   Orientation = phi
        %
        %
        % initialize
        orientation_tolerance = 1e-3;
        % empty warning stack
        warning( '' );
        % prepare vectors, must be column vectors
        x = x(:);
        y = y(:);
        % remove bias of the ellipse - to make matrix inversion more accurate. (will be added later on).
        mean_x = mean(x);
        mean_y = mean(y);
        x = x-mean_x;
        y = y-mean_y;
        % the estimation for the conic equation of the ellipse
        X = [x.^2, x.*y, y.^2, x, y ];
        a = sum(X)/(X'*X);
        % check for warnings
        if ~isempty( lastwarn )
            disp( 'stopped because of a warning regarding matrix inversion' );
            ellipse_t = [];
            return
        end
        % extract parameters from the conic equation
        [a,b,c,d,e] = deal( a(1),a(2),a(3),a(4),a(5) );
        % remove the orientation from the ellipse
        if ( min(abs(b/a),abs(b/c)) > orientation_tolerance )
            
            orientation_rad = 1/2 * atan( b/(c-a) );
            cos_phi = cos( orientation_rad );
            sin_phi = sin( orientation_rad );
            [a,b,c,d,e] = deal(...
                a*cos_phi^2 - b*cos_phi*sin_phi + c*sin_phi^2,...
                0,...
                a*sin_phi^2 + b*cos_phi*sin_phi + c*cos_phi^2,...
                d*cos_phi - e*sin_phi,...
                d*sin_phi + e*cos_phi );
            [mean_x,mean_y] = deal( ...
                cos_phi*mean_x - sin_phi*mean_y,...
                sin_phi*mean_x + cos_phi*mean_y );
        else
            orientation_rad = 0;
            cos_phi = cos( orientation_rad );
            sin_phi = sin( orientation_rad );
        end
        % check if conic equation represents an ellipse
        test = a*c;
        switch (1)
        case (test>0),  status = '';
        case (test==0), status = 'Parabola found';  warning( 'fit_ellipse: Did not locate an ellipse' );
        case (test<0),  status = 'Hyperbola found'; warning( 'fit_ellipse: Did not locate an ellipse' );
        end
        % if we found an ellipse return it's data
        if (test>0)
            
            % make sure coefficients are positive as required
            if (a<0), [a,c,d,e] = deal( -a,-c,-d,-e ); end
            
            % final ellipse parameters
            X0          = mean_x - d/2/a;
            Y0          = mean_y - e/2/c;
            F           = 1 + (d^2)/(4*a) + (e^2)/(4*c);
            [a,b]       = deal( sqrt( F/a ),sqrt( F/c ) );    
            long_axis   = 2*max(a,b);
            short_axis  = 2*min(a,b);
            % rotate the axes backwards to find the center point of the original TILTED ellipse
            R           = [ cos_phi sin_phi; -sin_phi cos_phi ];
            P_in        = R * [X0;Y0];
            X0_in       = P_in(1);
            Y0_in       = P_in(2);
            
            % pack ellipse into a structure
            ellipse_t = struct( ...
                'a',a,...
                'b',b,...
                'phi',orientation_rad,...
                'X0',X0,...
                'Y0',Y0,...
                'X0_in',X0_in,...
                'Y0_in',Y0_in,...
                'long_axis',long_axis,...
                'short_axis',short_axis,...
                'status','' );
        else
            % report an empty structure
            ellipse_t = struct( ...
                'a',[],...
                'b',[],...
                'phi',[],...
                'X0',[],...
                'Y0',[],...
                'X0_in',[],...
                'Y0_in',[],...
                'long_axis',[],...
                'short_axis',[],...
                'status',status );
        end
        % check if we need to plot an ellipse with it's axes.
        if (nargin>2) & ~isempty( axis_handle ) & (test>0)
            
            % rotation matrix to rotate the axes with respect to an angle phi
            R = [ cos_phi sin_phi; -sin_phi cos_phi ];
            
            % the axes
            ver_line        = [ [X0 X0]; Y0+b*[-1 1] ];
            horz_line       = [ X0+a*[-1 1]; [Y0 Y0] ];
            new_ver_line    = R*ver_line;
            new_horz_line   = R*horz_line;
            
            % the ellipse
            theta_r         = linspace(0,2*pi);
            ellipse_x_r     = X0 + a*cos( theta_r );
            ellipse_y_r     = Y0 + b*sin( theta_r );
            rotated_ellipse = R * [ellipse_x_r;ellipse_y_r];
            
            % draw
            hold_state = get( axis_handle,'NextPlot' );
            set( axis_handle,'NextPlot','add' );
            plot( new_ver_line(1,:),new_ver_line(2,:),'r' );
            plot( new_horz_line(1,:),new_horz_line(2,:),'r' );
            plot( rotated_ellipse(1,:),rotated_ellipse(2,:),'r' );
            set( axis_handle,'NextPlot',hold_state );
        end
            
            
        end
        
        function [displacement, velocity] = demodulate(app, Data, ellipsestruct,dt)
            lambda=1.55e-6;
            displacement=zeros(4,size(Data,2));
            velocity=zeros(4,size(Data,2));
            for i=1:4
                I_num=eval(strcat("app.I",num2str(i),".Value"));
                Q_num=eval(strcat("app.Q",num2str(i),".Value"));
                if I_num~=0 && Q_num~=0
                    I=Data(I_num,:);
                    Q=Data(Q_num,:);
                    a=ellipsestruct(i).a;
                    b=ellipsestruct(i).b;
                    phi=-ellipsestruct(i).phi;
                    X0=ellipsestruct(i).X0_in;
                    Y0=ellipsestruct(i).Y0_in;
                    M = [1/a,0;0,1/b]*[cos(phi),sin(phi);-sin(phi),cos(phi)]...
                         *([I;Q]-[X0;Y0]);
                    I_new = M(1,:);
                    Q_new = M(2,:);
                    phase = unwrap(angle(I_new + 1j*(Q_new)));
                    displacement(i,:) = -phase*lambda/4/pi;
                    velocity(i,:) = -gradient(displacement(i,:))/dt;
                end
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.mode=app.ModeDropDown.Value;
            app.samplingrate=str2num(app.FsDropDown.Value);
            app.samplingrate_record=str2num(app.FsDropDown_2.Value);
            app.segsize=app.SegmentSize.Value;
            app.inputrange=20000;
            app.impedance=1e6;
            app.segsize_record=app.SegmentSizeRecord.Value;
            app.DirectoryEditField.Value=string(pwd);
            app.DirectoryEditField_2.Value=string(pwd);
            app.directory=string(pwd);
           
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            app.mode = app.ModeDropDown.Value;
        end

        % Value changed function: FsDropDown, FsDropDown_3
        function FsDropDownValueChanged(app, event)
            app.samplingrate = str2num(app.FsDropDown.Value);
        end

        % Value changed function: FsDropDown_2, FsDropDown_4
        function FsDropDown_2ValueChanged(app, event)
            app.samplingrate_record= str2num(app.FsDropDown_2.Value);
        end

        % Selection changed function: InputRangeButtonGroup, 
        % ...and 1 other component
        function InputRangeButtonGroupSelectionChanged(app, event)
            selectedButton = app.InputRangeButtonGroup.SelectedObject;
            if selectedButton==app.V1Button
                app.inputrange=2000;
            end
            if selectedButton==app.V5Button
                app.inputrange=10000;
            end
            if selectedButton==app.V10Button
                app.inputrange=20000;
            end
        end

        % Selection changed function: ImpedanceButtonGroup, 
        % ...and 1 other component
        function ImpedanceButtonGroupSelectionChanged(app, event)
            selectedButton = app.ImpedanceButtonGroup.SelectedObject;
            if selectedButton==app.Ohm50Button
                app.impedance=50;
                app.TextArea.Value=[app.TextArea.Value;'impedance set to 50 Ohm';app.TextArea.Value];
            end
            if selectedButton==app.Ohm1MButton
                app.impedance=1000000;
                app.TextArea.Value=['impedance set to 1 MOhm';app.TextArea.Value];
            end
        end

        % Callback function
        function SegmentSizeRecordValueChanged(app, event)
            app.segsize_record = app.SegmentSizeRecord.Value;
            
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            app.name = app.NameEditField.Value;  
        end

        % Value changed function: DirectoryEditField_2
        function DirectoryEditFieldValueChanged(app, event)
            app.directory = app.DirectoryEditField_2.Value;
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            if value==true
                %initialize drivers
                systems = CsMl_Initialize;
                CsMl_ErrorHandler(systems);

                [ret, handle] = CsMl_GetSystem;
                app.handle=handle
                CsMl_ErrorHandler(ret);
                
                [ret, sysinfo] = CsMl_GetSystemInfo(app.handle);
                CsMl_ErrorHandler(ret);
                
                s = sprintf('-----Board name: %s\n', sysinfo.BoardName);
                app.TextArea.Value=[app.TextArea.Value;s];
                
                % Setup
                SetupDAQ(app,app.handle,app.mode,app.samplingrate,app.segsize, 0, app.impedance, app.inputrange, 1, 10000,-1,0,0);
                cla(app.UIAxes); 
                cla(app.UIAxes2); 
                ax=app.UIAxes;
                ax2=app.UIAxes2;
                xtime=[1:app.segsize].*1/app.samplingrate;
                
                h=animatedline(ax,'MaximumNumPoints',app.segsize);
                h2=animatedline(ax2,'MaximumNumPoints',app.segsize);
                clearpoints(h);
                clearpoints(h2);
            
                app.TextArea.Value=['continous mode activated';app.TextArea.Value];
                while app.StartButton.Value==true
                    [ret] = CsMl_Commit(app.handle);
                    if value~=true
                        break
                    end
                    CsMl_ErrorHandler(0,ret);
                    [ret, acqInfo] = CsMl_QueryAcquisition(app.handle);
                    %Acquire pre-trigger data and await trigger
                    ret = CsMl_Capture(app.handle);
                    CsMl_ErrorHandler(ret, 0, app.handle);
                        
                    status = CsMl_QueryStatus(app.handle);
                    while status ~= 0
                          status = CsMl_QueryStatus(app.handle);
                    end
                    transfer.Channel = 1;
                    transfer.Mode = CsMl_Translate('TimeStamp', 'TxMode');
                    transfer.Length = acqInfo.SegmentCount;
                     transfer.Segment = 1;
                    [ret, tsdata, tickfr] = CsMl_Transfer(handle, transfer);
                    transfer.Mode = CsMl_Translate('Default', 'TxMode');
                    transfer.Start = -acqInfo.TriggerHoldoff;
                    transfer.Length = acqInfo.SegmentSize;
                    MaskedMode = bitand(acqInfo.Mode, 15);
                    ChannelsPerBoard = sysinfo.ChannelCount / sysinfo.BoardCount;
                    ChannelSkip = ChannelsPerBoard / MaskedMode;
                    
                    % Format a string with the number of segments and channels so all filenames
                    % have the same number of characters.
        
                    Data=zeros(sysinfo.ChannelCount,app.segsize,acqInfo.SegmentCount); %array for zeros for each channel
                    
                    for channel = 1:ChannelSkip:sysinfo.ChannelCount
                        transfer.Channel = channel;
                        for i = 1:acqInfo.SegmentCount
                            transfer.Segment = i;
                            [ret, data, actual] = CsMl_Transfer(app.handle, transfer);
                            Data(channel,:,i)= data;
                        end
                        
                    addpoints(h,xtime,Data(1,:,1));
                    drawnow limitrate
                    Y=fft(Data(1,:,1));
                    P2 = abs(Y/app.segsize);
                    P1 = P2(1:app.segsize/2+1);
                    P1(2:end-1) = 2*P1(2:end-1);
                    f = app.samplingrate*(0:(app.segsize/2))/app.segsize;
                    addpoints(h2,f,P1);
                    drawnow limitrate
                    
                    
                    end
                end
                app.TextArea.Value=['continous mode ended';app.TextArea.Value];
                ret = CsMl_FreeSystem(app.handle);
                g=sprintf('systemrelease, value: %d', value);
                app.TextArea.Value=[g;app.TextArea.Value];
            end
            
        end

        % Value changed function: SegmentSize, SegmentSizeRecord_4, 
        % ...and 1 other component
        function SegmentSizeValueChanged(app, event)
            app.segsize= app.SegmentSize.Value;  
        end

        % Button pushed function: searchButton, searchButton_2
        function searchButtonPushed(app, event)
            app.directory = uigetdir;
            app.DirectoryEditField.Value=app.directory;
            app.DirectoryEditField_2.Value=app.directory;
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            %initialize drivers
            app.TextArea.Value=['initializing system...';app.TextArea.Value];
            systems = CsMl_Initialize;
            CsMl_ErrorHandler(systems);

            [ret, handle] = CsMl_GetSystem;
            app.handle=handle
            CsMl_ErrorHandler(ret);
                
            [ret, sysinfo] = CsMl_GetSystemInfo(app.handle);
            CsMl_ErrorHandler(ret);
                
            s = sprintf('-----Board name: %s\n', sysinfo.BoardName);
            app.TextArea.Value=[s;app.TextArea.Value];    
            % Setup
            SetupDAQ(app,app.handle,app.mode,app.samplingrate_record,app.segsize_record,app.PreTriggerEditField.Value, app.impedance, app.inputrange, app.NumSegments.Value, 10000, app.TriggerEditField.Value,app.TriggerdelayEditField,app.TriggerTimeoutEditField.Value)
            [ret] = CsMl_Commit(app.handle);
            CsMl_ErrorHandler(ret, 1, app.handle);
            [ret, acqInfo] = CsMl_QueryAcquisition(app.handle);
            %Acquire pre-trigger data and await trigger
            app.TextArea.Value=['Capturing...';app.TextArea.Value];
            ret = CsMl_Capture(app.handle);
            CsMl_ErrorHandler(ret, 1, app.handle);
                
            status = CsMl_QueryStatus(app.handle);
            while status ~= 0
                  status = CsMl_QueryStatus(app.handle);
            end
            transfer.Channel = 1;
            transfer.Mode = CsMl_Translate('TimeStamp', 'TxMode');
            transfer.Length = acqInfo.SegmentCount;
             transfer.Segment = 1;
            [ret, tsdata, tickfr] = CsMl_Transfer(handle, transfer);
            transfer.Mode = CsMl_Translate('Default', 'TxMode');
            transfer.Start = -acqInfo.TriggerHoldoff;
            transfer.Length = acqInfo.SegmentSize;
            MaskedMode = bitand(acqInfo.Mode, 15);
            ChannelsPerBoard = sysinfo.ChannelCount / sysinfo.BoardCount;
            ChannelSkip = ChannelsPerBoard / MaskedMode;
            
            % Format a string with the number of segments and channels so all filenames
            % have the same number of characters.
            app.TextArea.Value=['Transferring started...';app.TextArea.Value];

            Data=zeros(sysinfo.ChannelCount,app.segsize_record,acqInfo.SegmentCount); %array for zeros for each channel
            
            for channel = 1:ChannelSkip:sysinfo.ChannelCount
                transfer.Channel = channel;
                for i = 1:acqInfo.SegmentCount
                    transfer.Segment = i;
                    fprintf('Channel: %d\n',channel);
                    [ret, data, actual] = CsMl_Transfer(app.handle, transfer);
                    Data(channel,:,i)= data;
                end
            end
            ret = CsMl_FreeSystem(app.handle);
            app.TextArea.Value=['iSystem released succesfully';app.TextArea.Value];
            app.TextArea.Value=['transfer finished... writing to mat file started';app.TextArea.Value];
            save(strcat(app.directory,'\',app.name,'.mat'),'Data');
            app.TextArea.Value=['writing finished... start diplaying';app.TextArea.Value];
            app.Data_Matrix=reshape(Data,size(Data,1),[]);
            f = app.samplingrate_record*(0:(app.segsize_record/2))/app.segsize_record;
            cla(app.UIAxes); 
            cla(app.UIAxes2); 
            for channel = 1:ChannelSkip:sysinfo.ChannelCount 
                hold(app.UIAxes,"on");
                hold(app.UIAxes2,"on");
                plot(app.UIAxes,app.Data_Matrix(channel,:));
                Y=fft(Data(channel,:,1));
                P2 = abs(Y/app.segsize_record);
                P1 = P2(1:app.segsize_record/2+1);
                P1(2:end-1) = 2*P1(2:end-1);
                plot(app.UIAxes2,f,P1);
            end
            hold(app.UIAxes,"off");
            hold(app.UIAxes2,"off");

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {532, 532};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {548, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            ret = CsMl_FreeAllSystems;
        end

        % Value changed function: TriggerEditField
        function TriggerEditFieldValueChanged(app, event)
            value = app.TriggerEditField.Value;
            
        end

        % Value changed function: StartButton_IQ
        function StartButton_IQValueChanged(app, event)
            value = app.StartButton_IQ.Value;
            app.mode = app.ModeDropDown_3.Value;
            if value==true
                %initialize drivers
                systems = CsMl_Initialize;
                CsMl_ErrorHandler(systems);

                [ret, handle] = CsMl_GetSystem;
                app.handle=handle
                CsMl_ErrorHandler(ret);
                
                [ret, sysinfo] = CsMl_GetSystemInfo(app.handle);
                CsMl_ErrorHandler(ret);
                
                s = sprintf('-----Board name: %s\n', sysinfo.BoardName);
                app.TextArea.Value=[app.TextArea.Value;s];
                
                % Setup
                SetupDAQ(app,app.handle,app.mode,app.samplingrate,app.segsize, 0, app.impedance, app.inputrange, 1, 10000,-1,0,0);
                cla(app.UIAxes); 
                cla(app.UIAxes2); 
                ax=app.UIAxes;
                xlim(ax, [-app.inputrange/2/1000,app.inputrange/2/1000])
                ylim(ax, [-app.inputrange/2/1000,app.inputrange/2/1000])
                ax2=app.UIAxes2;
                xtime=[1:app.segsize].*1/app.samplingrate;
                
                if strcmp(app.mode,'Dual')
                    h_iq1=animatedline(ax,'MaximumNumPoints',app.segsize);
                    h2_i1=animatedline(ax2,'MaximumNumPoints',app.segsize);
                    clearpoints( h_iq1);
                    clearpoints( h2_i1);
                end
                
                if strcmp(app.mode,'Octal')
                    h_iq1=animatedline(ax,'MaximumNumPoints',app.segsize,'Color','r');
                    h_iq2=animatedline(ax,'MaximumNumPoints',app.segsize,'Color','b');
                    h_iq3=animatedline(ax,'MaximumNumPoints',app.segsize,'Color','g');
                    h_iq4=animatedline(ax,'MaximumNumPoints',app.segsize);
                    h2_i1=animatedline(ax2,'MaximumNumPoints',app.segsize);
                    clearpoints( h_iq1);
                    clearpoints( h_iq2);
                    clearpoints( h_iq3);
                    clearpoints( h_iq4);
                    clearpoints( h2_i1);

                end
                
            
                app.TextArea.Value=['continous mode activated';app.TextArea.Value];
                
                while app.StartButton_IQ.Value==true
                    [ret] = CsMl_Commit(app.handle);
                    if value~=true
                        break
                    end
                    CsMl_ErrorHandler(0,ret);
                    [ret, acqInfo] = CsMl_QueryAcquisition(app.handle);
                    %Acquire pre-trigger data and await trigger
                    ret = CsMl_Capture(app.handle);
                    CsMl_ErrorHandler(ret, 0, app.handle);
                        
                    status = CsMl_QueryStatus(app.handle);
                    while status ~= 0
                          status = CsMl_QueryStatus(app.handle);
                    end
                    transfer.Channel = 1;
                    transfer.Mode = CsMl_Translate('TimeStamp', 'TxMode');
                    transfer.Length = acqInfo.SegmentCount;
                     transfer.Segment = 1;
                    [ret, tsdata, tickfr] = CsMl_Transfer(handle, transfer);
                    transfer.Mode = CsMl_Translate('Default', 'TxMode');
                    transfer.Start = -acqInfo.TriggerHoldoff;
                    transfer.Length = acqInfo.SegmentSize;
                    MaskedMode = bitand(acqInfo.Mode, 15);
                    ChannelsPerBoard = sysinfo.ChannelCount / sysinfo.BoardCount;
                    ChannelSkip = ChannelsPerBoard / MaskedMode;
                    
                    % Format a string with the number of segments and channels so all filenames
                    % have the same number of characters.
        
                    Data=zeros(sysinfo.ChannelCount,app.segsize,acqInfo.SegmentCount); %array for zeros for each channel
                    
                    for channel = 1:ChannelSkip:sysinfo.ChannelCount
                        transfer.Channel = channel;
                        for i = 1:acqInfo.SegmentCount
                            transfer.Segment = i;
                            [ret, data, actual] = CsMl_Transfer(app.handle, transfer);
                            Data(channel,:,i)= data;
                        end
                    end    
                    Y=fft(Data(app.I1.Value,:,i));
                    P2 = abs(Y/app.segsize);
                    P1 = P2(1:app.segsize/2+1);
                    P1(2:end-1) = 2*P1(2:end-1);
                    f = app.samplingrate*(0:(app.segsize/2))/app.segsize;
                    drawnow limitrate
                    if strcmp(app.mode,'Dual')
                        addpoints(h_iq1,Data(app.I1.Value,:,i),Data(app.Q1.Value,:,i));
                        drawnow limitrate
                    end
                    if strcmp(app.mode,'Octal')
                        addpoints(h_iq1,Data(app.I1.Value,:,i),Data(app.Q1.Value,:,i));
                        addpoints(h_iq2,Data(app.I2.Value,:,i),Data(app.Q2.Value,:,i));
                        addpoints(h_iq3,Data(app.I3.Value,:,i),Data(app.Q3.Value,:,i));
                        addpoints(h_iq4,Data(app.I4.Value,:,i),Data(app.Q4.Value,:,i));
                        drawnow limitrate
                    end
                    
                end
                app.TextArea.Value=['continous mode ended';app.TextArea.Value];
                ret = CsMl_FreeSystem(app.handle);
                g=sprintf('systemrelease, value: %d', value);
                app.TextArea.Value=[g;app.TextArea.Value];
            end
            
        end

        % Value changed function: ModeDropDown_3
        function ModeDropDown_3ValueChanged(app, event)
            app.mode = app.ModeDropDown_3.Value;
            app.TextArea.Value=[app.ModeDropDown_3.Value;app.TextArea.Value]; 
            if strcmp(app.mode,'Single')
                app.TextArea.Value=['Single Mode not possible for IQ mode';app.TextArea.Value];                
            end
            if strcmp(app.mode,'Dual')
                app.I1.Value=1;
                app.Q1.Value=5;
                app.I2.Value=0;
                app.Q2.Value=0;
                app.I3.Value=0;
                app.Q3.Value=0;
                app.I4.Value=0;
                app.Q4.Value=0;
            end
            if strcmp(app.mode,'Octal')
                app.I1.Value=1;
                app.Q1.Value=2;
                app.I2.Value=3;
                app.Q2.Value=4;
                app.I3.Value=5;
                app.Q3.Value=6;
                app.I4.Value=7;
                app.Q4.Value=8;
            end
            
        end

        % Button pushed function: RecordDemodIQ
        function RecordDemodIQPushed(app, event)
            %initialize drivers
            %TO DO RENAME ALLL THINGS
            app.TextArea.Value=['initializing system...';app.TextArea.Value];
            drawnow();
            systems = CsMl_Initialize;
            CsMl_ErrorHandler(systems);
            
            [ret, handle] = CsMl_GetSystem;
            app.handle=handle
            CsMl_ErrorHandler(ret);
                
            [ret, sysinfo] = CsMl_GetSystemInfo(app.handle);
            CsMl_ErrorHandler(ret);
                
            s = sprintf('-----Board name: %s\n', sysinfo.BoardName);
            app.TextArea.Value=[s;app.TextArea.Value]; 
            drawnow();
            % Setup
            app.mode=app.ModeDropDown_3.Value;
            SetupDAQ(app,app.handle,app.mode,str2num(app.FsDropDown_4.Value),app.SegmentSizeRecord_4.Value ,app.PreTriggersEditField.Value, app.impedance, app.inputrange, app.NumSegmentsRecord_IQdemo.Value, 10000, app.TriggerEditField_2.Value,app.TriggerdelaysEditField,app.TriggerTimeoutusEditField.Value)
            [ret] = CsMl_Commit(app.handle);
            CsMl_ErrorHandler(ret, 1, app.handle);
            [ret, acqInfo] = CsMl_QueryAcquisition(app.handle);
            %Acquire pre-trigger data and await trigger
            app.TextArea.Value=['Capturing...';app.TextArea.Value];
            drawnow();
            ret = CsMl_Capture(app.handle);
            CsMl_ErrorHandler(ret, 1, app.handle);
                
            status = CsMl_QueryStatus(app.handle);
            while status ~= 0
                  status = CsMl_QueryStatus(app.handle);
            end
            transfer.Channel = 1;
            transfer.Mode = CsMl_Translate('TimeStamp', 'TxMode');
            transfer.Length = acqInfo.SegmentCount;
             transfer.Segment = 1;
            [ret, tsdata, tickfr] = CsMl_Transfer(handle, transfer);
            transfer.Mode = CsMl_Translate('Default', 'TxMode');
            transfer.Start = -acqInfo.TriggerHoldoff;
            transfer.Length = acqInfo.SegmentSize;
            MaskedMode = bitand(acqInfo.Mode, 15);
            ChannelsPerBoard = sysinfo.ChannelCount / sysinfo.BoardCount;
            ChannelSkip = ChannelsPerBoard / MaskedMode;
            
            % Format a string with the number of segments and channels so all filenames
            % have the same number of characters.
            app.TextArea.Value=['Transferring started...';app.TextArea.Value];
            drawnow();
            dt=1/str2num(app.FsDropDown_4.Value);
            if strcmp(app.DemodulationaveragingSwitch.Value,'Off')
                app.TextArea.Value=['Demodulation Off';app.TextArea.Value];
                Data=zeros(sysinfo.ChannelCount,acqInfo.SegmentSize,acqInfo.SegmentCount); 
                AverageData=zeros(4,acqInfo.SegmentSize);
                for i = 1:acqInfo.SegmentCount
                    transfer.Segment = i;
                    for channel = 1:ChannelSkip:sysinfo.ChannelCount
                        transfer.Segment = i;
                        transfer.Channel = channel;
                        [ret, data, actual] = CsMl_Transfer(app.handle, transfer);
                        Data(channel,:)= data;    
                    end 
                    AverageData= AverageData+Data./acqInfo.SegmentCount;
                end
                ret = CsMl_FreeSystem(app.handle);
                app.TextArea.Value=['iSystem released succesfully';app.TextArea.Value];
                app.TextArea.Value=['transfer finished... writing to mat file started';app.TextArea.Value];
                save(strcat(app.directory,'\',app.NameEditField_2.Value,'.mat'),'Data','AverageData');
                cla(app.timeAxes);
                plot(app.timeAxes,0:dt:(length(AverageData)-1)*dt,AverageData)
            end
            
            if strcmp(app.DemodulationaveragingSwitch.Value,'On')
                app.TextArea.Value=['Demodulation On';app.TextArea.Value];
                ellipse_fittings=load(strcat(app.directory,'\',app.fittingfileEditField.Value,'_fittings.mat'),'EllipseStruct');
                Data=zeros(sysinfo.ChannelCount,acqInfo.SegmentSize); 
                AverageDisplacement=zeros(4,acqInfo.SegmentSize);
                AverageVelocity=zeros(4,acqInfo.SegmentSize);
                for i = 1:acqInfo.SegmentCount
                    transfer.Segment = i;
                    for channel = 1:ChannelSkip:sysinfo.ChannelCount
                        transfer.Segment = i;
                        transfer.Channel = channel;
                        [ret, data, actual] = CsMl_Transfer(app.handle, transfer);
                        Data(channel,:)= data;
                        
                    end 
                    [segment_displacement,segment_velocity]=demodulate(app,Data,ellipse_fittings.EllipseStruct,dt);
                    AverageDisplacement = AverageDisplacement+segment_displacement./acqInfo.SegmentCount;
                    AverageVelocity= AverageVelocity+segment_velocity./acqInfo.SegmentCount;
                end
                ret = CsMl_FreeSystem(app.handle);
                app.TextArea.Value=['iSystem released succesfully';app.TextArea.Value];
                app.TextArea.Value=['transfer finished... writing to mat file started';app.TextArea.Value];
                save(strcat(app.directory,'\',app.NameEditField_2.Value,'.mat'),'Data','AverageDisplacement','AverageVelocity');
                app.TextArea.Value=['writing finished... start diplaying';app.TextArea.Value];
                cla(app.timeAxes);
                plot(app.timeAxes,0:dt:(length(AverageVelocity)-1)*dt,AverageVelocity)
            end
            
            app.Data_Matrix=reshape(Data,size(Data,1),[]);
            f = str2num(app.FsDropDown_4.Value)*(0:(acqInfo.SegmentSize/2))/acqInfo.SegmentSize;
            cla(app.UIAxes); 
            cla(app.UIAxes2); 
            for i=1:4
                I_num=eval(strcat("app.I",num2str(i),".Value"));
                Q_num=eval(strcat("app.Q",num2str(i),".Value"));
                    if I_num~=0 && Q_num~=0
                    hold(app.UIAxes,"on");
                    hold(app.UIAxes2,"on");
                    plot(app.UIAxes,app.Data_Matrix(I_num,:),app.Data_Matrix(Q_num,:));
                    Y=fft(Data(app.Q1.Value,:,1));
                    P2 = abs(Y/acqInfo.SegmentSize);
                    P1 = P2(1:acqInfo.SegmentSize/2+1);
                    P1(2:end-1) = 2*P1(2:end-1);
                    plot(app.UIAxes2,f,P1);
                    end
            end
            hold(app.UIAxes,"off");
            hold(app.UIAxes2,"off");
            
        end

        % Button pushed function: RecordIQsingle
        function RecordIQsinglePushed(app, event)
            %initialize drivers
            app.TextArea.Value=['initializing system...';app.TextArea.Value];
            systems = CsMl_Initialize;
            CsMl_ErrorHandler(systems);

            [ret, handle] = CsMl_GetSystem;
            app.handle=handle
            CsMl_ErrorHandler(ret);
                
            [ret, sysinfo] = CsMl_GetSystemInfo(app.handle);
            CsMl_ErrorHandler(ret);
            app.mode=app.ModeDropDown_3.Value;    
            s = sprintf('-----Board name: %s\n', sysinfo.BoardName);
            app.TextArea.Value=[s;app.TextArea.Value];    
            % Setup
            SetupDAQ(app,app.handle,app.mode,app.samplingrate,app.segsize,0, app.impedance, app.inputrange, 1, 10000, 0,0,0)
            % we need to add num segments
            
            [ret] = CsMl_Commit(app.handle);
            CsMl_ErrorHandler(ret, 1, app.handle);
            [ret, acqInfo] = CsMl_QueryAcquisition(app.handle);
            %Acquire pre-trigger data and await trigger
            app.TextArea.Value=['Capturing...';app.TextArea.Value];
            ret = CsMl_Capture(app.handle);
            CsMl_ErrorHandler(ret, 1, app.handle);
                
            status = CsMl_QueryStatus(app.handle);
            while status ~= 0
                  status = CsMl_QueryStatus(app.handle);
            end
            transfer.Channel = 1;
            transfer.Mode = CsMl_Translate('TimeStamp', 'TxMode');
            transfer.Length = acqInfo.SegmentCount;
             transfer.Segment = 1;
            [ret, tsdata, tickfr] = CsMl_Transfer(handle, transfer);
            transfer.Mode = CsMl_Translate('Default', 'TxMode');
            transfer.Start = -acqInfo.TriggerHoldoff;
            transfer.Length = acqInfo.SegmentSize;
            MaskedMode = bitand(acqInfo.Mode, 15);
            ChannelsPerBoard = sysinfo.ChannelCount / sysinfo.BoardCount;
            ChannelSkip = ChannelsPerBoard / MaskedMode;
            
            % Format a string with the number of segments and channels so all filenames
            % have the same number of characters.
            app.TextArea.Value=['Transferring started...';app.TextArea.Value];

            Data=zeros(sysinfo.ChannelCount,app.segsize,acqInfo.SegmentCount); %array for zeros for each channel
            Data=zeros(sysinfo.ChannelCount,app.segsize,acqInfo.SegmentCount); 
            for channel = 1:ChannelSkip:sysinfo.ChannelCount
                transfer.Channel = channel;
                for i = 1:acqInfo.SegmentCount
                    transfer.Segment = i;
                    [ret, data, actual] = CsMl_Transfer(app.handle, transfer);
                    Data(channel,:,i)= data;
                end
            end

            ret = CsMl_FreeSystem(app.handle);
            app.TextArea.Value=['iSystem released succesfully';app.TextArea.Value];
            app.TextArea.Value=['transfer finished... writing to mat file started';app.TextArea.Value];
            save(strcat(app.directory,'\',app.namefitfileEditField.Value,'.mat'),'Data');
            app.TextArea.Value=['writing finished... start diplaying';app.TextArea.Value];
            app.Data_Matrix=reshape(Data,size(Data,1),[]);
            f = app.samplingrate*(0:(app.segsize/2))/app.segsize;
            cla(app.UIAxes); 
            if app.mode=="Octal"
                for i = 1:4
                    hold(app.UIAxes,"on");
                    I_num=eval(strcat("app.I",num2str(i),".Value"));
                    Q_num=eval(strcat("app.Q",num2str(i),".Value"));
                    Data_I=app.Data_Matrix(I_num,:);
                    Data_Q=app.Data_Matrix(Q_num,:);
                    plot(app.UIAxes,Data_I,Data_Q);
                end
                hold(app.UIAxes,"off"); 
            end
            if app.mode == "Dual"
                    hold(app.UIAxes,"on");
                    I_num=eval(strcat("app.I",num2str(1),".Value"));
                    Q_num=eval(strcat("app.Q",num2str(1),".Value"));
                    Data_I=app.Data_Matrix(I_num,:);
                    Data_Q=app.Data_Matrix(Q_num,:);
                    plot(app.UIAxes,Data_I,Data_Q);
            end

        end

        % Button pushed function: FitEllipseButton
        function FitEllipseButtonPushed(app, event)
            % load
            data_struct=load(strcat(app.directory,'\',app.namefitfileEditField.Value,'.mat'),'Data');
            app.Data_Matrix=data_struct.Data;
            app.mode=app.ModeDropDown_3.Value; 
            cla(app.UIAxes);
            if app.mode=="Dual"
              
                hold(app.UIAxes,"on");
                I_num=eval(strcat("app.I",num2str(1),".Value"));
                Q_num=eval(strcat("app.Q",num2str(1),".Value"));
                app.TextArea.Value=[num2str(Q_num);app.TextArea.Value];
                Data_I=app.Data_Matrix(I_num,:);
                Data_Q=app.Data_Matrix(Q_num,:);
                plot(app.UIAxes,Data_I,Data_Q);
                ellipse=fit_ellipse(app,Data_I',Data_Q',app.UIAxes);
                EllipseStruct=[ellipse];
                EllipseStruct=[EllipseStruct ellipse];
            end
            if app.mode=="Octal"
                for i = 1:4
                    hold(app.UIAxes,"on");
                    I_num=eval(strcat("app.I",num2str(i),".Value"));
                    Q_num=eval(strcat("app.Q",num2str(i),".Value"));
                    app.TextArea.Value=[num2str(Q_num);app.TextArea.Value];
                    Data_I=app.Data_Matrix(I_num,:);
                    Data_Q=app.Data_Matrix(Q_num,:);
                    plot(app.UIAxes,Data_I,Data_Q);
                    hold(app.UIAxes,"on");
                    ellipse=fit_ellipse(app,Data_I',Data_Q',app.UIAxes);

                    if i==1
                       EllipseStruct=[ellipse];
                    else
                        EllipseStruct=[EllipseStruct ellipse];
                    end
                end
            end

            hold(app.UIAxes,"off"); 
            save(strcat(app.directory,'\',app.namefitfileEditField.Value,'_fittings.mat'),'EllipseStruct');
        end

        % Value changed function: DirectoryEditField
        function DirectoryEditFieldValueChanged2(app, event)
            app.directory= app.DirectoryEditField.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.NormalModeTab = uitab(app.TabGroup);
            app.NormalModeTab.Title = 'Normal Mode';
            app.ModeDropDown_2Label = uilabel(app.NormalModeTab);
            app.ModeDropDown_2Label.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.NormalModeTab);
            app.ModeDropDown.Items = {'Single', 'Dual', 'Octal'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'Single';
            app.FsDropDownLabel = uilabel(app.NormalModeTab);
            app.FsDropDownLabel.Text = 'Fs';
            app.FsDropDown = uidropdown(app.NormalModeTab);
            app.FsDropDown.Items = {'65e6', '5e6', '5e5'};
            app.FsDropDown.ValueChangedFcn = createCallbackFcn(app, @FsDropDownValueChanged, true);
            app.FsDropDown.Value = '5e5';
            app.InputRangeButtonGroup = uibuttongroup(app.NormalModeTab);
            app.InputRangeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InputRangeButtonGroupSelectionChanged, true);
            app.InputRangeButtonGroup.Title = 'Input Range';
            app.V1Button = uiradiobutton(app.InputRangeButtonGroup);
            app.V1Button.Text = '1V';
            app.V5Button = uiradiobutton(app.InputRangeButtonGroup);
            app.V5Button.Text = '5V';
            app.V10Button = uiradiobutton(app.InputRangeButtonGroup);
            app.V10Button.Text = '10V';
            app.V10Button.Value = true;
            app.ImpedanceButtonGroup = uibuttongroup(app.NormalModeTab);
            app.ImpedanceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImpedanceButtonGroupSelectionChanged, true);
            app.ImpedanceButtonGroup.Title = 'Impedance';
            app.Ohm50Button = uiradiobutton(app.ImpedanceButtonGroup);
            app.Ohm50Button.Text = '50 Ohm';
            app.Ohm1MButton = uiradiobutton(app.ImpedanceButtonGroup);
            app.Ohm1MButton.Text = '1 MOhm';
            app.Ohm1MButton.Value = true;
            app.StartButton = uibutton(app.NormalModeTab, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.SegmentSizeEditField_2Label = uilabel(app.NormalModeTab);
            app.SegmentSizeEditField_2Label.Text = 'SegmentSize';
            app.SegmentSize = uieditfield(app.NormalModeTab, 'numeric');
            app.SegmentSize.ValueChangedFcn = createCallbackFcn(app, @SegmentSizeValueChanged, true);
            app.SegmentSize.Value = 50000;
            app.RecordPanel = uipanel(app.NormalModeTab);
            app.RecordPanel.Title = 'Record Panel';
            app.NameEditField_3Label = uilabel(app.RecordPanel);
            app.NameEditField_3Label.Text = 'Name';
            app.NameEditField = uieditfield(app.RecordPanel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.DirectoryEditField_4Label = uilabel(app.RecordPanel);
            app.DirectoryEditField_4Label.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.RecordPanel, 'text');
            app.DirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @DirectoryEditFieldValueChanged2, true);
            app.RecordButton = uibutton(app.RecordPanel, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.searchButton = uibutton(app.RecordPanel, 'push');
            app.searchButton.ButtonPushedFcn = createCallbackFcn(app, @searchButtonPushed, true);
            app.searchButton.Text = 'search';
            app.FsDropDown_5Label = uilabel(app.RecordPanel);
            app.FsDropDown_5Label.Text = 'Fs';
            app.FsDropDown_2 = uidropdown(app.RecordPanel);
            app.FsDropDown_2.Items = {'65e6', '5e6', '5e5'};
            app.FsDropDown_2.ValueChangedFcn = createCallbackFcn(app, @FsDropDown_2ValueChanged, true);
            app.FsDropDown_2.Value = '5e5';
            app.SegmentSizeEditField_2Label_5 = uilabel(app.RecordPanel);
            app.SegmentSizeEditField_2Label_5.Text = 'SegmentSize';
            app.SegmentSizeRecord = uieditfield(app.RecordPanel, 'numeric');
            app.SegmentSizeRecord.RoundFractionalValues = 'on';
            app.SegmentSizeRecord.Value = 50000;
            app.TriggerEditField_3Label = uilabel(app.RecordPanel);
            app.TriggerEditField_3Label.Text = 'Trigger';
            app.TriggerEditField = uieditfield(app.RecordPanel, 'numeric');
            app.TriggerEditField.ValueChangedFcn = createCallbackFcn(app, @TriggerEditFieldValueChanged, true);
            app.TriggerEditField.Value = -1;
            app.PreTriggerEditFieldLabel = uilabel(app.RecordPanel);
            app.PreTriggerEditFieldLabel.Text = 'Pre-Trigger';
            app.PreTriggerEditField = uieditfield(app.RecordPanel, 'numeric');
            app.TriggerdelayEditField_3Label = uilabel(app.RecordPanel);
            app.TriggerdelayEditField_3Label.Text = 'Trigger delay';
            app.TriggerdelayEditField = uieditfield(app.RecordPanel, 'numeric');
            app.TriggerTimeoutEditField_3Label = uilabel(app.RecordPanel);
            app.TriggerTimeoutEditField_3Label.Text = 'Trigger Time-out';
            app.TriggerTimeoutEditField = uieditfield(app.RecordPanel, 'numeric');
            app.SegmentsLabel = uilabel(app.RecordPanel);
            app.SegmentsLabel.Text = '# Segments';
            app.NumSegments = uieditfield(app.RecordPanel, 'numeric');
            app.NumSegments.RoundFractionalValues = 'on';
            app.NumSegments.Value = 1;
            app.IQModeTab = uitab(app.TabGroup);
            app.IQModeTab.Title = 'IQ Mode';
            app.ModeDropDown_3Label = uilabel(app.IQModeTab);
            app.ModeDropDown_3Label.Text = 'Mode';
            app.ModeDropDown_3 = uidropdown(app.IQModeTab);
            app.ModeDropDown_3.Items = {'Dual', 'Octal'};
            app.ModeDropDown_3.ValueChangedFcn = createCallbackFcn(app, @ModeDropDown_3ValueChanged, true);
            app.ModeDropDown_3.Value = 'Dual';
            app.SegmentSizeEditField_2Label_3 = uilabel(app.IQModeTab);
            app.SegmentSizeEditField_2Label_3.Text = 'SegmentSize';
            app.SegmentSize_2 = uieditfield(app.IQModeTab, 'numeric');
            app.SegmentSize_2.ValueChangedFcn = createCallbackFcn(app, @SegmentSizeValueChanged, true);
            app.SegmentSize_2.Value = 50000;
            app.FsDropDown_3Label = uilabel(app.IQModeTab);
            app.FsDropDown_3Label.Text = 'Fs';
            app.FsDropDown_3 = uidropdown(app.IQModeTab);
            app.FsDropDown_3.Items = {'65e6', '5e6', '5e5', '5e4'};
            app.FsDropDown_3.ValueChangedFcn = createCallbackFcn(app, @FsDropDownValueChanged, true);
            app.FsDropDown_3.Value = '5e5';
            app.InputRangeButtonGroup_2 = uibuttongroup(app.IQModeTab);
            app.InputRangeButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @InputRangeButtonGroupSelectionChanged, true);
            app.InputRangeButtonGroup_2.Title = 'Input Range';
            app.V1Button_2 = uiradiobutton(app.InputRangeButtonGroup_2);
            app.V1Button_2.Text = '1V';
            app.V1Button_2.Value = true;
            app.V5Button_2 = uiradiobutton(app.InputRangeButtonGroup_2);
            app.V5Button_2.Text = '5V';
            app.V10Button_2 = uiradiobutton(app.InputRangeButtonGroup_2);
            app.V10Button_2.Text = '10V';
            app.ImpedanceButtonGroup_2 = uibuttongroup(app.IQModeTab);
            app.ImpedanceButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ImpedanceButtonGroupSelectionChanged, true);
            app.ImpedanceButtonGroup_2.Title = 'Impedance';
            app.Ohm50Button_2 = uiradiobutton(app.ImpedanceButtonGroup_2);
            app.Ohm50Button_2.Text = '50 Ohm';
            app.Ohm50Button_2.Value = true;
            app.MOhm1Button_2 = uiradiobutton(app.ImpedanceButtonGroup_2);
            app.MOhm1Button_2.Text = '1 MOhm';
            app.StartButton_IQ = uibutton(app.IQModeTab, 'state');
            app.StartButton_IQ.ValueChangedFcn = createCallbackFcn(app, @StartButton_IQValueChanged, true);
            app.StartButton_IQ.Text = 'Start';
            app.IQCirclesPanel = uipanel(app.IQModeTab);
            app.IQCirclesPanel.Title = 'IQ Circle(s)';
            app.Q1 = uieditfield(app.IQCirclesPanel, 'numeric');
            app.Q1.Value = 5;
            app.I1 = uieditfield(app.IQCirclesPanel, 'numeric');
            app.I1.Value = 1;
            app.Q2 = uieditfield(app.IQCirclesPanel, 'numeric');
            app.I2 = uieditfield(app.IQCirclesPanel, 'numeric');
            app.I3 = uieditfield(app.IQCirclesPanel, 'numeric');
            app.Q3 = uieditfield(app.IQCirclesPanel, 'numeric');
            app.Q4 = uieditfield(app.IQCirclesPanel, 'numeric');
            app.I4 = uieditfield(app.IQCirclesPanel, 'numeric');
            app.ChLabel = uilabel(app.IQCirclesPanel);
            app.ChLabel.Text = 'Ch';
            app.ChLabel_2 = uilabel(app.IQCirclesPanel);
            app.ChLabel_2.Text = 'Ch';
            app.IQ1Label = uilabel(app.IQCirclesPanel);
            app.IQ1Label.Text = 'IQ1';
            app.IQ2Label = uilabel(app.IQCirclesPanel);
            app.IQ2Label.Text = 'IQ2';
            app.IQ3Label = uilabel(app.IQCirclesPanel);
            app.IQ3Label.Text = 'IQ3';
            app.IQ4Label = uilabel(app.IQCirclesPanel);
            app.IQ4Label.Text = 'IQ4';
            app.RecordinganddemodulatePanel = uipanel(app.IQModeTab);
            app.RecordinganddemodulatePanel.Title = 'Recording and demodulate';
            app.NameEditField_2Label = uilabel(app.RecordinganddemodulatePanel);
            app.NameEditField_2Label.Text = 'Name';
            app.NameEditField_2 = uieditfield(app.RecordinganddemodulatePanel, 'text');
            app.DirectoryEditField_2Label = uilabel(app.RecordinganddemodulatePanel);
            app.DirectoryEditField_2Label.Text = 'Directory';
            app.DirectoryEditField_2 = uieditfield(app.RecordinganddemodulatePanel, 'text');
            app.DirectoryEditField_2.ValueChangedFcn = createCallbackFcn(app, @DirectoryEditFieldValueChanged, true);
            app.RecordDemodIQ = uibutton(app.RecordinganddemodulatePanel, 'push');
            app.RecordDemodIQ.ButtonPushedFcn = createCallbackFcn(app, @RecordDemodIQPushed, true);
            app.RecordDemodIQ.Text = 'Record';
            app.searchButton_2 = uibutton(app.RecordinganddemodulatePanel, 'push');
            app.searchButton_2.ButtonPushedFcn = createCallbackFcn(app, @searchButtonPushed, true);
            app.searchButton_2.Text = 'search';
            app.FsDropDown_4Label = uilabel(app.RecordinganddemodulatePanel);
            app.FsDropDown_4Label.Text = 'Fs';
            app.FsDropDown_4 = uidropdown(app.RecordinganddemodulatePanel);
            app.FsDropDown_4.Items = {'65e6', '5e6', '5e5'};
            app.FsDropDown_4.ValueChangedFcn = createCallbackFcn(app, @FsDropDown_2ValueChanged, true);
            app.FsDropDown_4.Value = '65e6';
            app.SegmentSizeEditField_2Label_6 = uilabel(app.RecordinganddemodulatePanel);
            app.SegmentSizeEditField_2Label_6.Text = 'SegmentSize';
            app.SegmentSizeRecord_4 = uieditfield(app.RecordinganddemodulatePanel, 'numeric');
            app.SegmentSizeRecord_4.RoundFractionalValues = 'on';
            app.SegmentSizeRecord_4.ValueChangedFcn = createCallbackFcn(app, @SegmentSizeValueChanged, true);
            app.SegmentSizeRecord_4.Value = 18432;
            app.TriggerEditField_2Label = uilabel(app.RecordinganddemodulatePanel);
            app.TriggerEditField_2Label.Text = 'Trigger';
            app.TriggerEditField_2 = uieditfield(app.RecordinganddemodulatePanel, 'numeric');
            app.TriggerEditField_2.Value = -1;
            app.PreTriggersEditFieldLabel = uilabel(app.RecordinganddemodulatePanel);
            app.PreTriggersEditFieldLabel.Text = 'Pre-Trigger [#s]';
            app.PreTriggersEditField = uieditfield(app.RecordinganddemodulatePanel, 'numeric');
            app.TriggerdelaysEditFieldLabel = uilabel(app.RecordinganddemodulatePanel);
            app.TriggerdelaysEditFieldLabel.Text = 'Trigger delay [#s]';
            app.TriggerdelaysEditField = uieditfield(app.RecordinganddemodulatePanel, 'numeric');
            app.TriggerTimeoutusEditFieldLabel = uilabel(app.RecordinganddemodulatePanel);
            app.TriggerTimeoutusEditFieldLabel.Text = 'Trigger Time-out [us]';
            app.TriggerTimeoutusEditField = uieditfield(app.RecordinganddemodulatePanel, 'numeric');
            app.TriggerTimeoutusEditField.Value = 1000000;
            app.SegmentsLabel_2 = uilabel(app.RecordinganddemodulatePanel);
            app.SegmentsLabel_2.Text = '# Segments';
            app.NumSegmentsRecord_IQdemo = uieditfield(app.RecordinganddemodulatePanel, 'numeric');
            app.NumSegmentsRecord_IQdemo.RoundFractionalValues = 'on';
            app.NumSegmentsRecord_IQdemo.Value = 1;
            app.fittingfileEditFieldLabel = uilabel(app.RecordinganddemodulatePanel);
            app.fittingfileEditFieldLabel.Text = 'fitting file ';
            app.fittingfileEditField = uieditfield(app.RecordinganddemodulatePanel, 'text');
            app.fittingfileEditField.Value = 'ellipses0';
            app.DemodulationaveragingSwitchLabel = uilabel(app.RecordinganddemodulatePanel);
            app.DemodulationaveragingSwitchLabel.Text = 'Demodulation & averaging';
            app.DemodulationaveragingSwitch = uiswitch(app.RecordinganddemodulatePanel, 'slider');
            app.DemodulationaveragingSwitch.Value = 'On';
            app.RecordIQsingle = uibutton(app.IQModeTab, 'push');
            app.RecordIQsingle.ButtonPushedFcn = createCallbackFcn(app, @RecordIQsinglePushed, true);
            app.RecordIQsingle.Text = 'Record';
            app.FitEllipseButton = uibutton(app.IQModeTab, 'push');
            app.FitEllipseButton.ButtonPushedFcn = createCallbackFcn(app, @FitEllipseButtonPushed, true);
            app.FitEllipseButton.Text = 'Fit Ellipse';
            app.namefitfileEditFieldLabel = uilabel(app.IQModeTab);
            app.namefitfileEditFieldLabel.Text = 'name fit-file';
            app.namefitfileEditField = uieditfield(app.IQModeTab, 'text');
            app.namefitfileEditField.Value = 'ellipses0';
            app.RightPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.RightPanel);
            app.UIAxes = uiaxes(app.Panel);
            xlabel(app.UIAxes, 'Amplitude')
            ylabel(app.UIAxes, 'Amplitude')
            app.timeAxes = uiaxes(app.Panel);
            xlabel(app.timeAxes, 'time')
            ylabel(app.timeAxes, 'Amplitude')
            app.UIAxes2 = uiaxes(app.Panel);
            xlabel(app.UIAxes2, 'f')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.TextArea = uitextarea(app.Panel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LDV_PA_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end